classdef WHISIT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Tab                            matlab.ui.container.TabGroup
        Analysis_Tab                   matlab.ui.container.Tab
        BFstackLabel                   matlab.ui.control.Label
        t1_Edit_BF_Fold                matlab.ui.control.EditField
        Ch1stackLabel                  matlab.ui.control.Label
        t1_Load_BF                     matlab.ui.control.Button
        t1_Alg_Panel                   matlab.ui.container.Panel
        t1_Alg_Radio                   matlab.ui.container.ButtonGroup
        Memb_2_CytoButton              matlab.ui.control.RadioButton
        Polar_SigButton                matlab.ui.control.RadioButton
        AvgSignalButton                matlab.ui.control.RadioButton
        Edit_MC_Memb_sz_3Label         matlab.ui.control.Label
        t1_Edit_MC_Memb_sz             matlab.ui.control.NumericEditField
        t1_Edit_PL_CH2_Search          matlab.ui.control.NumericEditField
        t1_Edit_PL_CH2_ScaleFact       matlab.ui.control.NumericEditField
        t1_Edit_PL_CH2_Peak_Rad        matlab.ui.control.NumericEditField
        Ch1_2Label                     matlab.ui.control.Label
        Ch2_2Label                     matlab.ui.control.Label
        SearchcircleEditFieldLabel     matlab.ui.control.Label
        t1_Edit_PL_CH1_Search          matlab.ui.control.NumericEditField
        ScaleFactorEditFieldLabel      matlab.ui.control.Label
        t1_Edit_PL_CH1_ScaleFact       matlab.ui.control.NumericEditField
        SignalRadiuspxLabel            matlab.ui.control.Label
        t1_Edit_PL_CH1_Peak_Rad        matlab.ui.control.NumericEditField
        START_t1                       matlab.ui.control.Button
        STOP_t1                        matlab.ui.control.Button
        BoxborderpxEditFieldLabel      matlab.ui.control.Label
        t1_Edit_BorderBox              matlab.ui.control.NumericEditField
        t1_ShowPlot                    matlab.ui.control.CheckBox
        SaveasLabel                    matlab.ui.control.Label
        t1_Exp_Title                   matlab.ui.control.EditField
        t1_Edit_WaitPlot               matlab.ui.control.NumericEditField
        Ch2stackLabel                  matlab.ui.control.Label
        t1_Edit_CH2_Fold               matlab.ui.control.EditField
        t1_Edit_CH1_Fold               matlab.ui.control.EditField
        t1_Load_CH2                    matlab.ui.control.Button
        t1_Load_CH1                    matlab.ui.control.Button
        t1_CheckBox_CH2                matlab.ui.control.CheckBox
        t1_Check_CH1_Marker            matlab.ui.control.CheckBox
        t1_Load_Det                    matlab.ui.control.Button
        DetmatLabel_2                  matlab.ui.control.Label
        t1_Edit_Det_file               matlab.ui.control.EditField
        t1_CheckBox_Track              matlab.ui.control.CheckBox
        t1_Load_Det2Track              matlab.ui.control.Button
        Det2TrackmatLabel              matlab.ui.control.Label
        t1_Edit_Det2Track              matlab.ui.control.EditField
        t1_Create_cloneList            matlab.ui.control.Button
        Label_CloneList                matlab.ui.control.Label
        t1_CheckBox_CreateCloneList    matlab.ui.control.CheckBox
        Lineage_Tab                    matlab.ui.container.Tab
        t3_Load_cloneList              matlab.ui.control.Button
        clonesmatfileLabel             matlab.ui.control.Label
        t3_Edit_cloneList              matlab.ui.control.EditField
        PLOT_t3                        matlab.ui.control.Button
        SizeborderlineLabel            matlab.ui.control.Label
        t3_EditField_Borderline        matlab.ui.control.NumericEditField
        LinethicknessLabel             matlab.ui.control.Label
        t3_EditField_LineSize          matlab.ui.control.NumericEditField
        DistancebtwlinesLabel          matlab.ui.control.Label
        t3_EditField_LinesDistance     matlab.ui.control.NumericEditField
        t3_CheckBox_DisplayPoles       matlab.ui.control.CheckBox
        ColorMapDropDownLabel          matlab.ui.control.Label
        t3_DropDown_LUT                matlab.ui.control.DropDown
        frameXminLabel                 matlab.ui.control.Label
        t3_EditField_fpmCH1            matlab.ui.control.NumericEditField
        PlottypeListBoxLabel           matlab.ui.control.Label
        t3_ListBox_PlotType            matlab.ui.control.ListBox
        LinetypeListBoxLabel           matlab.ui.control.Label
        t3_ListBox_LineType            matlab.ui.control.ListBox
        ValuetouseforplottingLabel     matlab.ui.control.Label
        t3_DropDownValue               matlab.ui.control.DropDown
        t3_CheckBox_DisplayExI         matlab.ui.control.CheckBox
        ValueRangeEditFieldLabel       matlab.ui.control.Label
        t3_ValueRange                  matlab.ui.control.EditField
        SegmentsizeEditFieldLabel      matlab.ui.control.Label
        t3_EditField_SegmentSize       matlab.ui.control.NumericEditField
        t3_Save_txt_CheckBox           matlab.ui.control.CheckBox
        t3_DisplayID_Radio             matlab.ui.container.ButtonGroup
        noneButton_3                   matlab.ui.control.RadioButton
        lineageButton                  matlab.ui.control.RadioButton
        manualtrackButton              matlab.ui.control.RadioButton
        t3_Load_cloneFolder            matlab.ui.control.Button
        loadFolderLabel                matlab.ui.control.Label
        t3_Edit_cloneFolder            matlab.ui.control.EditField
        t3_TypeAnalysis_Radio          matlab.ui.container.ButtonGroup
        IntergenerationalButton        matlab.ui.control.RadioButton
        SingleLineageButton            matlab.ui.control.RadioButton
        FounderCellLabel               matlab.ui.control.Label
        t3_FounderCell_DropDown        matlab.ui.control.DropDown
        saveplotteddataastxtfileLabel  matlab.ui.control.Label
        t3_Exp_Title                   matlab.ui.control.EditField
        t3_AutoSave_Radio              matlab.ui.container.ButtonGroup
        NoButton                       matlab.ui.control.RadioButton
        PDFButton                      matlab.ui.control.RadioButton
        TIFFButton                     matlab.ui.control.RadioButton
        Label_Asterix2                 matlab.ui.control.Label
        Label_Asterix1                 matlab.ui.control.Label
        t3_Display_Plot                matlab.ui.control.CheckBox
        t3_Summary_Plot                matlab.ui.control.CheckBox
        Manual_Tracking_Tab            matlab.ui.container.Tab
        START_t5                       matlab.ui.control.Button
        STOP_t5                        matlab.ui.control.Button
        BFstackEditFieldLabel          matlab.ui.control.Label
        t5_Edit_BF_Fold                matlab.ui.control.EditField
        DetmatLabel                    matlab.ui.control.Label
        t5_Edit_Det_File               matlab.ui.control.EditField
        t5_Load_BF                     matlab.ui.control.Button
        t5_Load_Det                    matlab.ui.control.Button
        t5_choose_Outline              matlab.ui.control.CheckBox
        t5_ff_forw_x1                  matlab.ui.control.Button
        t5_ff_back_x1                  matlab.ui.control.Button
        t5_ff_forw_x10                 matlab.ui.control.Button
        t5_ff_back_x10                 matlab.ui.control.Button
        t5_Edit_Frame                  matlab.ui.control.NumericEditField
        t5_Label_totFrames             matlab.ui.control.Label
        t5_Load_Track                  matlab.ui.control.Button
        TracktxtLabel                  matlab.ui.control.Label
        t5_Edit_Track_File             matlab.ui.control.EditField
        t5_B_Delete                    matlab.ui.control.Button
        t5_B_Add                       matlab.ui.control.Button
        t5_B_Delete_All                matlab.ui.control.Button
        SelectTrackLabel               matlab.ui.control.Label
        t5_SelectCellDropDown          matlab.ui.control.DropDown
        t5_B_SaveTrack                 matlab.ui.control.Button
        t5_B_Clear                     matlab.ui.control.Button
        t5_ShowTracks_Radio            matlab.ui.container.ButtonGroup
        noneButton                     matlab.ui.control.RadioButton
        cellpreviousframeButton        matlab.ui.control.RadioButton
        cellonlypresentframeButton_2   matlab.ui.control.RadioButton
        celluptopresentframeButton_2   matlab.ui.control.RadioButton
        cellallframesButton_2          matlab.ui.control.RadioButton
        allcellspreviousframeButton    matlab.ui.control.RadioButton
        allcellsonlypresentframeButton_2  matlab.ui.control.RadioButton
        allcellsuptopresentframeButton_2  matlab.ui.control.RadioButton
        allcellsallframesButton_2      matlab.ui.control.RadioButton
        t5_showAid_CheckBox            matlab.ui.control.CheckBox
        t5_choose_Track                matlab.ui.control.CheckBox
        t5_Color_ButtonGroup           matlab.ui.container.ButtonGroup
        RedButton                      matlab.ui.control.RadioButton
        YellowButton                   matlab.ui.control.RadioButton
        t5_TrackSize_ButtonGroup       matlab.ui.container.ButtonGroup
        smallButton                    matlab.ui.control.RadioButton
        mediumButton                   matlab.ui.control.RadioButton
        largeButton                    matlab.ui.control.RadioButton
        t5_CellOutline_ButtonGroup     matlab.ui.container.ButtonGroup
        noneButton_2                   matlab.ui.control.RadioButton
        GreenButton                    matlab.ui.control.RadioButton
        AstrackButton                  matlab.ui.control.RadioButton
        t1_Exp_Title_2Label            matlab.ui.control.Label
        t5_TrackFile_Title             matlab.ui.control.EditField
        t5_B_CreateReport              matlab.ui.control.Button
        TrackingReport_Label           matlab.ui.control.Label
        t5_B_Combine_Det2Track         matlab.ui.control.Button
        Combine_Label                  matlab.ui.control.Label
        t5_B_RemoveLast                matlab.ui.control.Button
        TextOUT                        matlab.ui.control.TextArea
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc;
            
            % Variable APP_opt store all the major information on the status of WHISIT and
            % the choises, parameters chosen by the user in the GUI
            global APP_opt ;
            
            % Always initialize an empty cell 
            global CellTracks;          CellTracks = {};
             
            % my_DataBase variable, store and save analysis
            APP_opt.my_DB = '' ; 
            
            % Buttons Options
            APP_opt.START_t1 = 0 ;             
            APP_opt.PLOT_t3 = 0 ;
            APP_opt.START_t5 = 0 ;
            APP_opt.BREAK = 1 ;            
            APP_opt.ERROR = 0 ;
             
            APP_opt.name_delimiters = {'_', '.'};
            
            % Path, folder and prefix used for the Bright Field images 
            APP_opt.t1_path_BF = ''; 
            APP_opt.t1_foldName_BF = '' ;
            APP_opt.t1_Prefix_BF = '';
            
            % Path, folder and prefix used for the Fluorescence channel 1 images
            APP_opt.t1_path_CH1 = '' ;
            APP_opt.t1_foldName_CH1 = '' ; 
            APP_opt.t1_Prefix_CH1 = '';          
            
            % Path, folder and prefix used for the Fluorescence channel 2 images
            APP_opt.t1_path_CH2 = '';         
            APP_opt.t1_foldName_CH2 = '' ;
            APP_opt.t1_Prefix_CH2 = '';           
            
            % Path and filename used for the cell Detection.mat file
            APP_opt.t1_path_Det = '' ;
            APP_opt.t1_file_Det = '' ;            
                                   
            APP_opt.t1_exp_name = '' ;      % User defined experiment name
            
            % Path and filename used for the Detection_2_Track file
            APP_opt.t1_path_Det2Track = '' ;
            APP_opt.t1_file_Det2Track = '' ;
            
            
            %% --- TAB 3 -----------------------------------------------------------------------            
            % Path and filename used for the clone cell's Detection.mat file
            APP_opt.t3_path_cloneList = '' ;
            APP_opt.t3_file_cloneList = '' ; 
            APP_opt.t3_path_cloneFolder = '' ;
            APP_opt.t3_fold_cloneFolder = '' ;
            
            % Values and Parameters for plotting lineages (tab 3)       
            % Inizialize the values for the choosing the plotting type            
            Str_value = strsplit(app.t3_ListBox_PlotType.Value, '-'); 
            APP_opt.t3_PlotOpt_A = str2num(Str_value{1}(1));    
            Str_value = strsplit(app.t3_ListBox_LineType.Value, '-');
            APP_opt.t3_PlotOpt_B = str2num(Str_value{1}(1));            
            Str_value = strsplit(app.t3_DropDownValue.Value, '-');
            APP_opt.t3_PlotOpt_C = str2num(Str_value{1}(1));   
            
            APP_opt.t3_PlotOpt_FounderN =  [];    % Cell Lineage to plot
            
            APP_opt.t3_fpm_CH1 = app.t3_EditField_fpmCH1.Value;            
            APP_opt.t3_display_PL  = app.t3_CheckBox_DisplayPoles.Value ;
            APP_opt.t3_display_ExI = app.t3_CheckBox_DisplayExI.Value ;      
            APP_opt.t3_display_cID = 0 ;
            
            APP_opt.t3_Value_Range = app.t3_ValueRange.Value ;         %STRCMPI = COMPARE CASE INSENSITIVE
            APP_opt.t3_height_PlotLine = app.t3_EditField_LineSize.Value;
            APP_opt.t3_Dist_btw_PlotLines = app.t3_EditField_LinesDistance.Value; 
            APP_opt.t3_BorderLineWidth = app.t3_EditField_Borderline.Value;
            APP_opt.t3_B4_LenStep = app.t3_EditField_SegmentSize.Value;
            
            APP_opt.t3_ColorMap_LUT = (app.t3_DropDown_LUT.Value);            
            
            % Save plotted data in a .txt file
            APP_opt.t3_choose_Save_txt = app.t3_Save_txt_CheckBox.Value;
            APP_opt.t3_choose_AutoSave_Plot = 0 ;
            APP_opt.t3_choose_Display_Plot = app.t3_Display_Plot.Value ;
            APP_opt.t3_choose_Summary_Plot = app.t3_Summary_Plot.Value ;
            APP_opt.t3_exp_name = '';                          % User defined experiment name            
            APP_opt.t3_Generation_VS_Single = 1 ;              % Analyse and plot a single file or all the .mat files in a folder
            APP_opt.t3_intergen_srcFiles = struct();        % Store a list of the .mat file for intergeneration analysis
            
            
            % Variables and input parameters for analysis (tab 1 and tab 2)
            % % % % % % % %             APP_opt_frame_rate_Lng = 1 ;
            APP_opt.BorderBox = app.t1_Edit_BorderBox.Value ;                % extra border area to add to a cell cropped image
            APP_opt.Memb_sz = app.t1_Edit_MC_Memb_sz.Value;                  % 2   , number of circle to extend the membrane area: 
                                                                             % roughly add x pixel width from perimeter inward to the cell to membrane area
            APP_opt.plot_pause = app.t1_Edit_WaitPlot.Value ;                % 2 [sec], display analysis plot for X sec,
            
            APP_opt.CH1_Search_Circle = app.t1_Edit_PL_CH1_Search.Value ;    % 10  , Cell pole coordinates to chose for circle fit
            APP_opt.CH1_ScaleFact = app.t1_Edit_PL_CH1_ScaleFact.Value;      % 0.7 , [%], scale foactor for pole circle
            APP_opt.CH1_Peak_R = app.t1_Edit_PL_CH1_Peak_Rad.Value ;           % 2.5 , Signal at pole radius
                       
            APP_opt.CH2_Search_Circle = app.t1_Edit_PL_CH2_Search.Value ;    % 10  , Cell pole coordinates to chose for circle fit
            APP_opt.CH2_ScaleFact = app.t1_Edit_PL_CH2_ScaleFact.Value;      % 0.7 , [%], scale foactor for pole circle
            APP_opt.CH2_Peak_R = app.t1_Edit_PL_CH2_Peak_Rad.Value ;           % 2.5 , Signal at pole radius
                                                       
            % Det file was manually tracked or not: if yes, perform lineage and clone_List analysis
            APP_opt.t1_choose_ManualTrack  =  app.t1_CheckBox_Track.Value;
           
            APP_opt.choice_plot = 0 ;           % Display analysis process by plotting for each cell
            APP_opt.choice_AIS = 1 ;            % Average Intensity Signal
            APP_opt.choice_M2C = 0 ;            % Memb_2_Cyto
            APP_opt.choice_PL = 0 ;             % PolarSign
            % Analyse one or two fluorescent channels
            APP_opt.t1_choose_Chan = 1;         % Tab_1:  1 = one channel;    2 = two channels


            %% --- TAB 5 -----------------------------------------------------------------------
            APP_opt.t5_ff = 1 ;        % keep track of frame number in manual tracking
            
            % Path, folder and prefix used for the Bright Field images
            APP_opt.t5_path_BF = '';              
            APP_opt.t5_foldName_BF = '' ; 
            APP_opt.t5_Prefix_BF = '';
            
            % Path and filename used for the cell Detection.mat file           
            APP_opt.t5_path_Det = '';             
            APP_opt.t5_fileName_Det = '' ;
            
            % Path and filename used for the manual tracking .txt file
            APP_opt.t5_path_Track = '' ;          
            APP_opt.t5_fileName_Track = '' ;
            
            APP_opt.t5_exp_name = '';    % Name for the result track.txt file
            
            APP_opt.t5_display_Det = app.t5_choose_Outline.Value ;
            APP_opt.t5_display_Track = 0 ;            % Disply track type
            APP_opt.t5_display_Color = [.9 .2 0];     % Display track with color RGB  
            APP_opt.t5_display_Marker = 10 ;          % Displayed marker size  
            APP_opt.t5_display_Outline = 0 ;          % cell outline style 
            
            % Displayed colored frame border to aid tracking 
            APP_opt.t5_display_TrackAid = app.t5_showAid_CheckBox.Value;
            
            % The ColorOrder property of the axes contains the color order. To change the color order.
            % Here we set different default values for the ColorOrder property.
            co48 = [ 0.3922    0.7843    1.0000;     1.0000    0.0392         0;
                     0.0784    0.8627         0;     1.0000    0.8431         0;
                     0.1373    0.5490    1.0000;     1.0000    0.5137    0.9804;
                     1.0000    0.5882         0;     0.1569    0.7451    0.4706;
                     0.8627    0.0784    0.3137;     0.7451    0.9412    0.2549;
                     1.0000    0.7059    0.1961;          0    0.7059    1.0000;
                     0.8039    0.8039         0;     0.8627    0.3137    0.2353;
                     1.0000    0.7137    0.7569;     0.4706    0.9412         0;
                     0.3922    0.5882    0.9412;     1.0000    0.4157    0.4157;
                     0.9608    0.2039    0.7059;     0.9412    0.5490    0.4706;
                     0.2549    0.8824    0.8157;     0.9412    0.9412    0.7843;
                     1.0000    0.5098    0.6667;     0.7451    0.2431    1.0000;
                     0.9608    0.3137         0;     0.7451    0.9412    0.2549;
                     0.2745    0.3922    0.9020;          0    0.9608    1.0000;
                     0.8235    0.4314    0.3137;     0.5137    0.4353    1.0000;
                     1.0000    0.2431    0.5882;     1.0000    0.9255    0.5490;
                     0.6784    1.0000    0.0784;     0.1961    0.6275    0.7843;
                          0    0.7843    0.5882;     0.7843    0.6784         0;
                     0.7804    0.0824    0.5216;     0.9490    0.9490         0;
                          0         0    0.9804;     0.8549    0.4392    0.8392;
                     1.0000         0    1.0000;     0.5882    0.9608    1.0000;
                     0.6275    1.0000    0.5882;          0    0.8078    0.8235;
                     0.6078    0.1961    0.9333;     0.5294    0.8078    0.9804;
                     0.8627    0.7843    0.3529;     0.6275    0.4706    0.8627;
                     0.2353    1.0000    0.3922 ] ;
            set(groot,'defaultAxesColorOrder',co48)
            
            %-------------------------------------------------------------------------------------------------------------------------------------
            %------------------------------------------------------------------------------------------------------------------------------------- 
            
        end

        % Button pushed function: PLOT_t3
        function PLOT_t3_ButtonPushed(app, event)
            global APP_opt ;
            APP_PLOT_t3 = 1;
            % Disable Start & Stop in other tabs
            app.START_t1.Enable = 'off';
            app.START_t5.Enable = 'off';
            
            app.TextOUT.Value = sprintf('\n%s\n', 'Plotting ...');
            app.TextOUT.BackgroundColor = [1 1 1] ;       % White bkgr
            app.UIFigure.Color  = [0.75 0.3 0.3] ;        % Red bkgr
            
            Plot_Lng_MAIN_Fnc(app) ;
            
            if APP_opt.ERROR == 0
                app.TextOUT.Value = sprintf('\n%s\n', '... Idle ...' );
                app.TextOUT.BackgroundColor = [0.65 1 0.4] ;       % green bkgr
            elseif APP_opt.ERROR > 0
                app.TextOUT.BackgroundColor = [0.75 0.3 0.3] ;     % red bkgr
                APP_opt.ERROR = 0;
            end
            
            APP_PLOT_t3 = 0;
            % Re-enable the Start & Stop in other tabs
            app.START_t1.Enable = 'on';      
            app.PLOT_t3.Enable  = 'on'; 
            app.START_t5.Enable = 'on';
            
            app.UIFigure.Color = [0.65 0.65 0.65] ;
                        
        end

        % Button pushed function: START_t1
        function START_t1_ButtonPushed(app, event)
            %% START 1 _____________________________________________________________________________________________________________________________
            % --------------------------------------------------------------------------------------------------------------
            global APP_opt ;
            APP_opt.START_t1 = 1;
            APP_opt.BREAK = 0 ;
            % Disable Start & Stop in other tabs
            app.START_t1.Enable = 'off';     
            app.PLOT_t3.Enable  = 'off';
            app.START_t5.Enable = 'off';     
            app.STOP_t5.Enable  = 'off';
            
            app.TextOUT.BackgroundColor = [1 1 1] ;       % White bkgr
            app.UIFigure.Color  = [0.75 0.3 0.3] ;        % Red bkgr
                       
            app.TextOUT.Value = sprintf('\n%s\n', 'Processing ...');
            
            t1_MAIN_Analysis(app) ;
            
            if APP_opt.ERROR == 0
                if APP_opt.BREAK == 0 
                    app.TextOUT.Value = sprintf('\n%s\n', '... Idle ...' );
                elseif APP_opt.BREAK == 1
                    app.TextOUT.Value = sprintf('%s\n%s', 'Process aborted: Data analysed is lost', '... Idle ...');                 
                end                
                if APP_opt.BREAK ~= 1
                    app.TextOUT.BackgroundColor = [0.65 1 0.4] ;       % green bkgr
                end
            elseif APP_opt.ERROR > 0
                app.TextOUT.BackgroundColor = [0.75 0.3 0.3] ;       % green bkgr
                APP_opt.ERROR = 0;
            end
                         
            APP_opt.START_t1 = 0;
            APP_opt.BREAK = 1;
            % Re-enable the Start & Stop in other tabs
            app.START_t1.Enable = 'on';      
            app.PLOT_t3.Enable  = 'on';  
            app.START_t5.Enable = 'on';      
            app.STOP_t5.Enable  = 'on';
            app.UIFigure.Color = [0.65 0.65 0.65] ;
            
        end

        % Button pushed function: START_t5
        function START_t5_ButtonPushed(app, event)
            %% START 5 _____________________________________________________________________________________________________________________________
            % --------------------------------------------------------------------------------------------------------------
            global APP_opt ;
            
            if isempty(APP_opt.t5_path_BF) && isempty(APP_opt.t5_foldName_BF)
               app.TextOUT.Value = sprintf('\n%s', 'First load a stack of BF images');         
               app.TextOUT.BackgroundColor = [1 1 1] ;        % White bkgr
               app.UIFigure.Color = [0.75 0.3 0.3] ;          % Grey bkgr
               
            else
                APP_opt.START_t5 = 1;
                APP_opt.BREAK = 0 ;
                % Disable Start & Stop in other tabs
                app.START_t1.Enable = 'off';     
                app.PLOT_t3.Enable  = 'off';                
                app.START_t5.Enable = 'off';   
               
                app.TextOUT.BackgroundColor = [1 1 1] ;        % White bkgr
                app.UIFigure.Color  = [0.00 0.45 0.75] ;       % Blue bkgr            
                app.TextOUT.Value = sprintf('\n%s\n', 'Processing ...');
                           
                t5_MAIN_ManualTrack(app) ;
                
                if APP_opt.ERROR == 0
                    if APP_opt.BREAK == 0 
                        app.TextOUT.Value = sprintf('\n%s\n', '... Idle ...' );
                    elseif APP_opt.BREAK == 1
                        app.TextOUT.Value = sprintf('%s\n%s', 'Process aborted: tracked data was not saved', '... Idle ...');                 
                    end                
                    if APP_opt.BREAK ~= 1
                        app.TextOUT.BackgroundColor = [0.65 1 0.4] ;       % green bkgr
                    end
                elseif APP_opt.ERROR > 0
                    app.TextOUT.BackgroundColor = [0.75 0.3 0.3] ;         % red bkgr
                    APP_opt.ERROR = 0;
                end
                             
                APP_opt.START_t5 = 0;
                APP_opt.BREAK = 1;
                % Re-enable the Start & Stop in other tabs
                app.START_t1.Enable = 'on';      
                app.PLOT_t3.Enable  = 'on';
                app.START_t5.Enable = 'on';   
                
                app.UIFigure.Color = [0.65 0.65 0.65] ;         % Grey bkgr
            end
        end

        % Button pushed function: STOP_t1
        function STOP_t1_ButtonPushed(app, event)
            %% STOP 1 _____________________________________________________________________________________________________________________________
            % --------------------------------------------------------------------------------------------------------------
            global APP_opt ;
            APP_opt.BREAK = 1 ;
            APP_opt.ERROR = 0
            app.TextOUT.BackgroundColor = [1 1 1] ;       % White bkgr
            app.UIFigure.Color = [0.65 0.65 0.65] ;
            app.START_t1.Enable = 'on';        
            app.PLOT_t3.Enable  = 'on';            
            app.START_t5.Enable = 'on';       
            app.STOP_t5.Enable  = 'on'; 
        end

        % Button pushed function: STOP_t5
        function STOP_t5_ButtonPushed(app, event)
            %% STOP 5 _____________________________________________________________________________________________________________________________
            % --------------------------------------------------------------------------------------------------------------
            global APP_opt ;
            APP_opt.START_t5 = 0;
            APP_opt.BREAK = 1;
            APP_opt.ERROR = 0 ;
            
            app.TextOUT.BackgroundColor = [1 1 1] ;       % White bkgr
            app.UIFigure.Color = [0.65 0.65 0.65] ;
            app.START_t1.Enable = 'on';       
            app.PLOT_t3.Enable  = 'on';
            app.START_t5.Enable = 'on';
            
            % if it is open, close the tracking figure  (99)
            if ishandle(APP_opt.fig)
                hold off;
                close(APP_opt.fig);
            end
        end

        % Selection changed function: t1_Alg_Radio
        function t1_Alg_Radio_SelectionChanged(app, event)
            global APP_opt ;
            selectedRadio = app.t1_Alg_Radio.SelectedObject;
            if strcmp(selectedRadio.Text , 'Avg Signal')
                APP_opt.choice_AIS = 1 ;        % Avg Intensity Signal  
                APP_opt.choice_M2C = 0 ;        % Memb_2_Cyto
                APP_opt.choice_PL = 0 ;         % Polar_Sig                
                % ----- CHANNEL 1                                   ----- CHANNEL 2
                app.t1_Edit_PL_CH1_Search.Enable    = 'off';        app.t1_Edit_PL_CH2_Search.Enable    = 'off';
                app.t1_Edit_PL_CH1_Peak_Rad.Enable  = 'off';        app.t1_Edit_PL_CH2_Peak_Rad.Enable    = 'off';
                app.t1_Edit_PL_CH1_ScaleFact.Enable = 'off';        app.t1_Edit_PL_CH2_ScaleFact.Enable = 'off';
                app.t1_Edit_MC_Memb_sz.Enable       = 'off'; 
                   
            elseif strcmp(selectedRadio.Text , 'Memb_2_Cyto')
                APP_opt.choice_AIS = 0 ;        % Avg Intensity Signal
                APP_opt.choice_M2C = 1 ;        % Memb_2_Cyto
                APP_opt.choice_PL = 0 ;         % Polar_Sig                
                % ----- CHANNEL 1                                   ----- CHANNEL 2
                app.t1_Edit_PL_CH1_Search.Enable    = 'off';        app.t1_Edit_PL_CH2_Search.Enable    = 'off';
                app.t1_Edit_PL_CH1_Peak_Rad.Enable  = 'off';        app.t1_Edit_PL_CH2_Peak_Rad.Enable    = 'off';
                app.t1_Edit_PL_CH1_ScaleFact.Enable = 'off';        app.t1_Edit_PL_CH2_ScaleFact.Enable = 'off';
                app.t1_Edit_MC_Memb_sz.Enable       = 'on';
                
                
            elseif strcmp(selectedRadio.Text , 'Polar_Sig')              
                APP_opt.choice_AIS = 0 ;        % Avg Intensity Signal
                APP_opt.choice_M2C = 0 ;        % Memb_2_Cyto
                APP_opt.choice_PL = 1 ;         % Polar_Sig
                % ----- CHANNEL 1                                   ----- CHANNEL 2
                app.t1_Edit_PL_CH1_Search.Enable    = 'on';         app.t1_Edit_PL_CH2_Search.Enable    = 'on';
                app.t1_Edit_PL_CH1_Peak_Rad.Enable    = 'on';       app.t1_Edit_PL_CH2_Peak_Rad.Enable    = 'on';
                app.t1_Edit_PL_CH1_ScaleFact.Enable = 'on';         app.t1_Edit_PL_CH2_ScaleFact.Enable = 'on';
                app.t1_Edit_MC_Memb_sz.Enable       = 'off';
        
            end            
        end

        % Value changed function: t1_CheckBox_CH2
        function t1_CheckBox_CH2_ValueChanged(app, event)
            global APP_opt ;
            value = app.t1_CheckBox_CH2.Value;
            if value == 0
                act = 'off';   
                APP_opt.t1_choose_Chan = 1;    % Only 1 channel
            elseif value == 1
                act = 'on';
                APP_opt.t1_choose_Chan = 2;    % analyse 2 channels
            end
            app.t1_Load_CH2.Enable = act ;
            app.t1_Edit_CH2_Fold.Enable = act ;
        end

        % Value changed function: t1_Edit_BorderBox
        function t1_Edit_BorderBox_ValueChanged(app, event)
            global APP_opt ;
            APP_opt.BorderBox = app.t1_Edit_BorderBox.Value ;          
        end

        % Value changed function: t1_Edit_MC_Memb_sz
        function t1_Edit_MC_Memb_sz_ValueChanged(app, event)
            global APP_opt ;
            APP_opt.Memb_sz = app.t1_Edit_MC_Memb_sz.Value ;  
        end

        % Value changed function: t1_Edit_PL_CH1_Peak_Rad
        function t1_Edit_PL_CH1_Peak_Rad_ValueChanged(app, event)
            global APP_opt ;
            APP_opt.CH1_Peak_R = app.t1_Edit_PL_CH1_Peak_Rad.Value ;          
        end

        % Value changed function: t1_Edit_PL_CH1_ScaleFact
        function t1_Edit_PL_CH1_ScaleFact_ValueChanged(app, event)
            global APP_opt ;
            APP_opt.CH1_ScaleFact = app.t1_Edit_PL_CH1_ScaleFact.Value ;
        end

        % Value changed function: t1_Edit_PL_CH1_Search
        function t1_Edit_PL_CH1_Search_ValueChanged(app, event)
            global APP_opt ;
            APP_opt.CH1_Search_Circle = app.t1_Edit_PL_CH1_Search.Value ;
        end

        % Value changed function: t1_Edit_PL_CH2_Peak_Rad
        function t1_Edit_PL_CH2_Peak_Rad_ValueChanged(app, event)
            global APP_opt ;
            APP_opt.CH2_Peak_R = app.t1_Edit_PL_CH2_Peak_Rad.Value;
        end

        % Value changed function: t1_Edit_PL_CH2_ScaleFact
        function t1_Edit_PL_CH2_ScaleFact_ValueChanged(app, event)
            global APP_opt ;
            APP_opt.CH2_ScaleFact = app.t1_Edit_PL_CH2_ScaleFact.Value ;            
        end

        % Value changed function: t1_Edit_PL_CH2_Search
        function t1_Edit_PL_CH2_Search_ValueChanged(app, event)
            global APP_opt ;
            APP_opt.CH2_Search_Circle = app.t1_Edit_PL_CH2_Search.Value ;
        end

        % Value changed function: t1_Edit_WaitPlot
        function t1_Edit_WaitPlot_ValueChanged(app, event)
            global APP_opt ;
            APP_opt.plot_pause = app.t1_Edit_WaitPlot.Value;
        end

        % Value changed function: t1_Exp_Title
        function t1_Exp_Title_ValueChanged(app, event)
            global APP_opt ;
            APP_opt.t1_exp_name = app.t1_Exp_Title.Value;            
        end

        % Button pushed function: t1_Load_BF
        function t1_Load_BF_ButtonPushed(app, event)
            global APP_opt ;
            
            FullPath = uigetdir; 
            if FullPath == 0
                   FullPath = [];
                   app.TextOUT.Value = sprintf('\n%s\n%s',  'No path provided for BF stack !!!');
                   return
            end            
            [PathName, FoldName]  = fileparts(FullPath) ;            
            APP_opt.t1_path_BF = [PathName '/'];
            APP_opt.t1_foldName_BF = FoldName ;
            app.t1_Edit_BF_Fold.Value = [APP_opt.t1_path_BF , APP_opt.t1_foldName_BF];
            
            % Access stack folder and index all the file inside the folder that ends with .tif
            srcFiles = dir( fullfile([APP_opt.t1_path_BF '/' APP_opt.t1_foldName_BF], '*.tif'));
            cell_srcFiles = struct2cell( srcFiles );
            % remove directories files from the list
            APP_opt.t1_srcFiles_BF = srcFiles(~[cell_srcFiles{5,:}]) ;
            cell_srcFiles = struct2cell( APP_opt.t1_srcFiles_BF );   
            [sort_name] = sort( cell_srcFiles(1,:) );
             
            % Take first .tif filename as prefix. 
            % Filename format must be in form:  prefix_NNNNN.tif ; separation by '_' is necessary.
            cnt = -1;            yy = 0;
            while cnt == -1  &&  yy < size(APP_opt.t1_srcFiles_BF, 1)
                yy = yy+1;
                % split first filename that is a .tif
                strFile = strsplit( APP_opt.t1_srcFiles_BF(yy).name , APP_opt.name_delimiters);
                if length(strFile) >=3  &&  strcmp(strFile(end), 'tif')
                    APP_opt.t1_Prefix_BF = strsplit(sort_name{yy} , '_');
                    APP_opt.t1_Prefix_BF = APP_opt.t1_Prefix_BF(1:end-1);
                    APP_opt.t1_Prefix_BF = strjoin(APP_opt.t1_Prefix_BF , '_');
                    cnt = +2;
                end 
            end
            if cnt == -1        % then we have not found any tif correctly named
                app.TextOUT.Value = sprintf('\n%s\n%s',  ['!!! FL stack contain uncorrect .tif filename !!!'],...
                                           ['Format should be in form:   filename_xxxx.tif']);
                app.TextOUT.BackgroundColor = [0.75 0.3 0.3] ;
                return;
            end
            
        end

        % Button pushed function: t1_Load_CH1
        function t1_Load_CH1_ButtonPushed(app, event)
            global APP_opt ;
            FullPath = uigetdir; 
            if FullPath == 0
                   FullPath = [];
                   app.TextOUT.Value = sprintf('\n%s\n%s',  'No path provided for Channel 1 stack !!!');
                   return
            end            
            [PathName, FoldName]  = fileparts(FullPath) ;
            APP_opt.t1_path_CH1 = [PathName '/'];
            APP_opt.t1_foldName_CH1 = FoldName ;
            app.t1_Edit_CH1_Fold.Value = [APP_opt.t1_path_CH1 , APP_opt.t1_foldName_CH1];
            
            % Access stack folder and index all the file inside the folder that ends with .tif
            srcFiles = dir( fullfile([APP_opt.t1_path_CH1 '/' APP_opt.t1_foldName_CH1], '*.tif'));
            cell_srcFiles = struct2cell( srcFiles );
            % remove directories files from the list
            APP_opt.t1_srcFiles_CH1 = srcFiles(~[cell_srcFiles{5,:}]) ;
            cell_srcFiles = struct2cell( APP_opt.t1_srcFiles_CH1 );   
            [sort_name] = sort( cell_srcFiles(1,:) );
            
            % Take first .tif filename as prefix. 
            % Filename format must be in form:  prefix_nnnnx.tif ; separation by '_' is necessary.
            cnt = -1;            yy = 0;
            while cnt == -1  &&  yy < size(APP_opt.t1_srcFiles_CH1, 1)
                yy = yy+1;
                % split first filename that is a .tif
                strFile = strsplit( APP_opt.t1_srcFiles_CH1(yy).name , APP_opt.name_delimiters);
                if length(strFile) >=3  &&  strcmp(strFile(end), 'tif')
                    APP_opt.t1_Prefix_CH1 = strsplit(sort_name{yy} , '_');
                    APP_opt.t1_Prefix_CH1 = APP_opt.t1_Prefix_CH1(1:end-1);
                    APP_opt.t1_Prefix_CH1 = strjoin(APP_opt.t1_Prefix_CH1 , '_');
                    cnt = +2;
                end 
            end
            if cnt == -1        % then we have not found any tif correctly named
                app.TextOUT.Value = sprintf('\n%s\n%s',  ['!!! FL stack contain uncorrect .tif filename !!!'],...
                                           ['Format should be in form:   filename_xxxx.tif']);
                app.TextOUT.BackgroundColor = [0.75 0.3 0.3] ;
                return;
            end              
            
            
        end

        % Button pushed function: t1_Load_CH2
        function t1_Load_CH2_ButtonPushed(app, event)
            global APP_opt ;
            FullPath = uigetdir; 
            if FullPath == 0
                   FullPath = [];
                   app.TextOUT.Value = sprintf('\n%s\n%s',  'No path provided for Channel 2 stack !!!');
                   return
            end            
            [PathName, FoldName]  = fileparts(FullPath) ;
            APP_opt.t1_path_CH2 = [PathName '/'];
            APP_opt.t1_foldName_CH2 = FoldName ;
            app.t1_Edit_CH2_Fold.Value = [APP_opt.t1_path_CH2 , APP_opt.t1_foldName_CH2];
            
            % Access stack folder and index all the file inside the folder that ends with .tif
            srcFiles = dir( fullfile([APP_opt.t1_path_CH2 '/' APP_opt.t1_foldName_CH2],'*.tif'));
            cell_srcFiles = struct2cell( srcFiles );
            % remove directories files from the list
            APP_opt.t1_srcFiles_CH2 = srcFiles(~[cell_srcFiles{5,:}]) ;
            cell_srcFiles = struct2cell( APP_opt.t1_srcFiles_CH2 );   
            [sort_name] = sort( cell_srcFiles(1,:) );
            
            % Take first .tif filename as prefix. 
            % Filename format must be in form:  prefix_nnnnx.tif ; separation by '_' is necessary.
            cnt = -1;            yy = 0;
            while cnt == -1  &&  yy < size(APP_opt.t1_srcFiles_CH2, 1)
                yy = yy+1;
                % split first filename that is a .tif
                strFile = strsplit( APP_opt.t1_srcFiles_CH2(yy).name , APP_opt.name_delimiters);
                if length(strFile) >=3  &&  strcmp(strFile(end), 'tif')
                    APP_opt.t1_Prefix_CH2 = strsplit(sort_name{yy} , '_');
                    APP_opt.t1_Prefix_CH2 = APP_opt.t1_Prefix_CH2(1:end-1);
                    APP_opt.t1_Prefix_CH2 = strjoin(APP_opt.t1_Prefix_CH2 , '_'); 
                    cnt = +2;
                end 
            end
            if cnt == -1        % then we have not found any tif correctly named
                app.TextOUT.Value = sprintf('\n%s\n%s',  ['!!! FL stack contain uncorrect .tif filename !!!'],...
                                           ['Format should be in form:   filename_xxxx.tif']);
                app.TextOUT.BackgroundColor = [0.75 0.3 0.3] ;
                return;
            end          
                
        end

        % Callback function
        function t1_Load_Det_ButtonPushed(app, event)
            global APP_opt ;
            
            [FileName, PathName] = uigetfile({'*.mat'},'Select Oufti Detection.mat file'); 
            if FileName == 0 
                   FileName = [] ;
                   PathName = [] ;
                   app.TextOUT.Value = sprintf('\n%s\n%s',  'No detection.mat file provided !!!');
                   return
            end                        
            APP_opt.t1_path_Det = PathName ;
            APP_opt.t1_file_Det = FileName ;
            app.t1_Edit_Det_file.Value = [APP_opt.t1_path_Det , APP_opt.t1_file_Det];
        end

        % Value changed function: t1_ShowPlot
        function t1_ShowPlot_ValueChanged(app, event)
            global APP_opt ;
            APP_opt.choice_plot = app.t1_ShowPlot.Value ;
        end

        % Value changed function: t3_CheckBox_DisplayExI
        function t3_CheckBox_DisplayExI_ValueChanged(app, event)
            global APP_opt ;
            value = app.t3_CheckBox_DisplayExI.Value;
            if value == 0
                act = 'off';
                APP_opt.t3_display_ExI = 0 ;
            elseif value == 1
                act = 'on';
                APP_opt.t3_display_ExI = 1 ;
            end
        end

        % Value changed function: t3_CheckBox_DisplayPoles
        function t3_CheckBox_DisplayPoles_ValueChanged(app, event)
            global APP_opt ;
            value = app.t3_CheckBox_DisplayPoles.Value;
            if value == 0
                act = 'off';
                APP_opt.t3_display_PL = 0 ;
            elseif value == 1
                act = 'on';
                APP_opt.t3_display_PL = 1 ;
            end
        end

        % Value changed function: t3_DropDownValue
        function t3_DropDownValue_ValueChanged(app, event)
            global APP_opt ;
            value = app.t3_DropDownValue.Value;
            Str_value = strsplit(value, '-');
            APP_opt.t3_PlotOpt_C = str2num(Str_value{1}(1));
        end

        % Value changed function: t3_DropDown_LUT
        function t3_DropDown_LUT_ValueChanged(app, event)
            global APP_opt ;            
            APP_opt.t3_ColorMap_LUT = (app.t3_DropDown_LUT.Value);
        end

        % Value changed function: t3_EditField_Borderline
        function t3_EditField_Borderline_ValueChanged(app, event)
            global APP_opt ;
            APP_opt.t3_BorderLineWidth  = app.t3_EditField_Borderline.Value;            
        end

        % Value changed function: t3_EditField_LineSize
        function t3_EditField_LineSize_ValueChanged(app, event)
            global APP_opt ;  
            APP_opt.t3_height_PlotLine = app.t3_EditField_LineSize.Value;           
        end

        % Value changed function: t3_EditField_LinesDistance
        function t3_EditField_LinesDistance_ValueChanged(app, event)
            global APP_opt ;
            APP_opt.t3_Dist_btw_PlotLines = app.t3_EditField_LinesDistance.Value;
        end

        % Value changed function: t3_EditField_SegmentSize
        function t3_EditField_SegmentSize_ValueChanged(app, event)
            global APP_opt ;
            APP_opt.t3_B4_LenStep = app.t3_EditField_SegmentSize.Value;
        end

        % Value changed function: t3_EditField_fpmCH1
        function t3_EditField_fpmCH1_ValueChanged(app, event)
            global APP_opt ;
            APP_opt.t3_fpm_CH1 = app.t3_EditField_fpmCH1.Value;
        end

        % Value changed function: t3_ListBox_LineType
        function t3_ListBox_LineType_ValueChanged(app, event)
            global APP_opt ;
            value = app.t3_ListBox_LineType.Value;
            Str_value = strsplit(value, '-');
            APP_opt.t3_PlotOpt_B = str2num(Str_value{1}(1));
            % update the list in DropDownValue_3 accordingly
            switch APP_opt.t3_PlotOpt_B
                case 1
                    app.t3_DropDownValue.Items = {'1 - Whole Cell', '2 - Membrane', ...
                                                  '3 - Old_pole', '4 - New_pole' };
                    app.t3_DropDownValue.Value = app.t3_DropDownValue.Items{1};
                    Str_value_2 = strsplit(app.t3_DropDownValue.Value, '-');
                    APP_opt.t3_PlotOpt_C = str2num(Str_value_2{1}(1));
                case 2
                    app.t3_DropDownValue.Items = {'1 - Old_pole | New_pole', '2 - Cytosol | Membrane', ...
                                                  '3 - Old_pole | Cytosol | New_pole'};
                    app.t3_DropDownValue.Value = app.t3_DropDownValue.Items{1};
                    Str_value_2 = strsplit(app.t3_DropDownValue.Value, '-');
                    APP_opt.t3_PlotOpt_C = str2num(Str_value_2{1}(1));
                case 3
                    app.t3_DropDownValue.Items = {'1 - Cytosol / Membrane'  , '2 - Membrane / Cytosol' , ...
                                                 '3 - Old_pole / New_pole' , '4 - New_pole / Old_pole' , ...
                                                 '5 - Cytosol / Poles'     , '6 - Poles / Cytosol' };
                    app.t3_DropDownValue.Value = app.t3_DropDownValue.Items{1};
                    Str_value_2 = strsplit(app.t3_DropDownValue.Value, '-');
                    APP_opt.t3_PlotOpt_C = str2num(Str_value_2{1}(1));
                case 4
                    app.t3_DropDownValue.Items = {'1 - Average (All)', '2 - Average Membrane Signal', ...
                                                  '3 - Average Cutosol Signal'};
                    app.t3_DropDownValue.Value = app.t3_DropDownValue.Items{1};
                    Str_value_2 = strsplit(app.t3_DropDownValue.Value, '-');
                    APP_opt.t3_PlotOpt_C = str2num(Str_value_2{1}(1));
            end             
        end

        % Value changed function: t3_ListBox_PlotType
        function t3_ListBox_PlotType_ValueChanged(app, event)
            global APP_opt ;
            value = app.t3_ListBox_PlotType.Value;
            Str_value = strsplit(value, '-'); 
            APP_opt.t3_PlotOpt_A = str2num(Str_value{1}(1));
        end

        % Button pushed function: t3_Load_cloneList
        function t3_Load_cloneList_ButtonPushed(app, event)
            global APP_opt 
            global clone_List;
            
            [FileName, PathName] = uigetfile({'*.mat'},'Select clone_List.mat file');
            if FileName == 0 
                   FileName = [] ;
                   PathName = [] ;
                   app.TextOUT.Value = sprintf('\n%s\n%s',  'No clone_List.mat file provided !!!');
                   return
            end            
            APP_opt.t3_path_cloneList = PathName ;
            APP_opt.t3_file_cloneList = FileName ;
            app.t3_Edit_cloneList.Value = [APP_opt.t3_path_cloneList , APP_opt.t3_file_cloneList]; 
           
            % Immediately lfind how many Founder cells are present and update the DropDown Menu
            Find_FounderCell(app)
        end

        % Value changed function: t3_ValueRange
        function t3_ValueRange_ValueChanged(app, event)
            global APP_opt ;
            temp_Value = app.t3_ValueRange.Value;
            Value_Range = strsplit(temp_Value, ':')
            if length(Value_Range) ~= 2
                app.TextOUT.Value = sprintf('\n%s\n%s',  ['!!! Format Value Range is not correct !!!'],...
                                    ['Format must be in for  XX:YY. Place min:max for automatic detection of range']);
                app.TextOUT.BackgroundColor = [0.75 0.3 0.3] ;       % red bkgr
                APP_opt.ERROR = 1; 
                return
            end
            [nstr, status] = str2num(Value_Range{1}) ;    % if status == 0, conversion was not successful  
            if status == 0  &&  ~strcmpi(nstr, 'MIN')
                app.TextOUT.Value = sprintf('\n%s\n%s',  ['!!! Format Value Range is not correct !!!'],...
                                    ['Format must be in for  XX:YY. Place min:max for automatic detection of range']);
                app.TextOUT.BackgroundColor = [0.75 0.3 0.3] ;       % red bkgr
                APP_opt.ERROR = 1; 
                return
            end
            [nstr, status] = str2num(Value_Range{2}) ;    % if status == 0, conversion was not successful  
            if status == 0  &&  ~strcmpi(nstr, 'MAX')
                app.TextOUT.Value = sprintf('\n%s\n%s',  ['!!! Format Value Range is not correct !!!'],...
                                    ['Format must be in for  XX:YY. Place min:max for automatic detection of range']);
                app.TextOUT.BackgroundColor = [0.75 0.3 0.3] ;       % red bkgr
                APP_opt.ERROR = 1; 
                return
            end
            APP_opt.t3_Value_Range = temp_Value ;
        end

        % Button pushed function: t5_B_Add
        function t5_B_Add_ButtonPushed(app, event)
            BFun_Add_Cell(app)
        end

        % Button pushed function: t5_B_Clear
        function t5_B_Clear_ButtonPushed(app, event)
            BFun_Clear_Cell(app)
        end

        % Button pushed function: t5_B_Delete
        function t5_B_DeleteButtonPushed(app, event)
            BFun_Delete(app)
        end

        % Button pushed function: t5_B_Delete_All
        function t5_B_Delete_All_ButtonPushed(app, event)
            BFun_Delete_All(app)
        end

        % Button pushed function: t5_B_SaveTrack
        function t5_B_SaveTrack_ButtonPushed(app, event)
            BFun_SaveTracks(app)
        end

        % Selection changed function: t5_CellOutline_ButtonGroup
        function t5_CellOutline_ButtonGroupSelectionChanged(app, event)
            global APP_opt ;
            selectedRadio = app.t5_CellOutline_ButtonGroup.SelectedObject;
            
            if  strcmp( selectedRadio.Text ,   'none')
                APP_opt.t5_display_Outline = 0 ;     % cell outline style  
                if APP_opt.START_t5 == 1       % only if we are actively tracking
                    ReFresh_Frame;         % REFRESH the frame and update displayed options
                end
                
            elseif strcmp(selectedRadio.Text , 'Green')
                APP_opt.t5_display_Outline = 1 ;     % cell outline style   
                if APP_opt.START_t5 == 1       % only if we are actively tracking
                    ReFresh_Frame;
                end
                
            elseif strcmp(selectedRadio.Text , 'As track')
                APP_opt.t5_display_Outline = 2 ;     % cell outline style  
                if APP_opt.START_t5 == 1       % only if we are actively tracking
                    ReFresh_Frame;
                end
            end
        end

        % Selection changed function: t5_Color_ButtonGroup
        function t5_Color_ButtonGroupSelectionChanged(app, event)
            global APP_opt ;
            selectedRadio = app.t5_Color_ButtonGroup.SelectedObject;
           
            if  strcmp( selectedRadio.Text ,   'Red')
                APP_opt.t5_display_Color = [.9 .2 0];    % Display track with color RGB      
                if APP_opt.START_t5 == 1       % only if we are actively tracking
                    ReFresh_Frame;         % REFRESH the frame and update displayed options
                end
                
            elseif strcmp(selectedRadio.Text , 'Yellow')
                APP_opt.t5_display_Color = [1 .9 0];    % Display track with color RGB                 
                if APP_opt.START_t5 == 1       % only if we are actively tracking
                    ReFresh_Frame;
                end
            end
            
        end

        % Value changed function: t5_Edit_Frame
        function t5_Edit_Frame_ValueChanged(app, event)
            global APP_opt ;
            value = app.t5_Edit_Frame.Value ; 
            
            if value < 1 || value > size(APP_opt.t5_srcFiles_BF,1)
                app.TextOUT.Value = sprintf('\n%s\n%s%d',  ['!!! Frame number is outsize stack range !!!'],...
                                           ['Frames go from: 1 to '], size(APP_opt.t5_srcFiles_BF,1));
                app.TextOUT.BackgroundColor = [0.75 0.3 0.3] ;
                
            else
                delta = value - APP_opt.t5_ff;    
                if delta > 0
                    fr_forward( app, delta );    % go forward of n-frames = delta
                elseif delta < 0
                    fr_backward( app, -delta );       % go back of n-frames = delta
                end                
                app.TextOUT.Value = sprintf('\n%s',  ['... Idle ...']);
                app.TextOUT.BackgroundColor = [1 1 1] ;         % White bkgr
            end
            
        end

        % Button pushed function: t5_Load_BF
        function t5_Load_BF_ButtonPushed(app, event)
            global APP_opt ;
            FullPath = uigetdir; 
            if FullPath == 0
                   FullPath = [];
                   app.TextOUT.Value = sprintf('\n%s\n%s',  'No path provided for BF stack !!!');
                   return
            end            
            [PathName, FoldName]  = fileparts(FullPath) ;
            APP_opt.t5_path_BF = [PathName '/'];
            APP_opt.t5_foldName_BF = FoldName ;
            app.t5_Edit_BF_Fold.Value = [APP_opt.t5_path_BF , APP_opt.t5_foldName_BF];
            
            % Access stack folder and index all the file inside the folder that ends with .tif            
            srcFiles = dir( fullfile([APP_opt.t5_path_BF '/' APP_opt.t5_foldName_BF], '*.tif'));
            cell_srcFiles = struct2cell( srcFiles );
            % remove directories files from the list
            APP_opt.t5_srcFiles_BF = srcFiles(~[cell_srcFiles{5,:}]) ;
            cell_srcFiles = struct2cell( APP_opt.t5_srcFiles_BF );   
            [sort_name] = sort( cell_srcFiles(1,:) );
            
            
            % Take first .tif filename as prefix. 
            % Filename format must be in form:  prefix_nnnnx.tif ; separation by '_' is necessary.
            cnt = -1;            yy = 0;
            while cnt == -1  &&  yy < size(APP_opt.t5_srcFiles_BF, 1)
                yy = yy+1;
                % split first filename that is a .tif
                strFile = strsplit( APP_opt.t5_srcFiles_BF(yy).name , APP_opt.name_delimiters);
                if length(strFile) >=3  &&  strcmp(strFile(end), 'tif')
                    APP_opt.t5_Prefix_BF = strsplit(sort_name{yy} , '_');
                    APP_opt.t5_Prefix_BF = APP_opt.t5_Prefix_BF(1:end-1);
                    APP_opt.t5_Prefix_BF = strjoin(APP_opt.t5_Prefix_BF , '_');
                    cnt = +2;
                end 
            end
            if cnt == -1        % then we have not found any tif correctly named
                app.TextOUT.Value = sprintf('\n%s\n%s',  ['!!! FL stack contain uncorrect .tif filename !!!'],...
                                           ['Format should be in form:   filename_xxxx.tif']);
                app.TextOUT.BackgroundColor = [0.75 0.3 0.3] ;
                return;
            end 
        end

        % Button pushed function: t5_Load_Det
        function t5_Load_Det_ButtonPushed(app, event)
            global APP_opt ;
            
            [FileName, PathName] = uigetfile({'*.mat'},'Select Oufti Detection.mat file');
            if FileName == 0 
                   FileName = [] ;
                   PathName = [] ;
                   app.TextOUT.Value = sprintf('\n%s\n%s',  'No detection.mat file provided !!!');
                   return
            end            
            APP_opt.t5_path_Det = PathName ;
            APP_opt.t5_fileName_Det = FileName ;
            app.t5_Edit_Det_File.Value = [APP_opt.t5_path_Det , APP_opt.t5_fileName_Det];                 
         
        end

        % Button pushed function: t5_Load_Track
        function t5_Load_Track_ButtonPushed(app, event)
            global APP_opt ;
            [FileName, PathName] = uigetfile({'*.txt'},'Select Manual_Track.txt file');
            if FileName == 0
                   FileName = [] ;
                   PathName = [] ;
                   app.TextOUT.Value = sprintf('\n%s\n%s',  'No Manual_Track.txt file provided !!!');
                   return
            end  
            APP_opt.t5_path_Track = PathName ;
            APP_opt.t5_fileName_Track = FileName ;
            app.t5_Edit_Track_File.Value = [APP_opt.t5_path_Track , APP_opt.t5_fileName_Track];                 
            
        end

        % Value changed function: t5_SelectCellDropDown
        function t5_SelectCellDropDown_ValueChanged(app, event)
            BFun_Sele_Cell(app)
        end

        % Selection changed function: t5_ShowTracks_Radio
        function t5_ShowTracks_Radio_SelectionChanged(app, event)
            global APP_opt ;
            selectedRadio = app.t5_ShowTracks_Radio.SelectedObject;
            
            if  strcmp( selectedRadio.Text ,   'none')
                APP_opt.t5_display_Track = 0 ;
                if APP_opt.START_t5 == 1       % only if we are actively tracking
                    ReFresh_Frame;         % REFRESH the frame and update displayed options
                end
                
            elseif strcmp(selectedRadio.Text , '# cell: previous frame')
                APP_opt.t5_display_Track = 1 ;
                if APP_opt.START_t5 == 1       % only if we are actively tracking
                    ReFresh_Frame;
                end
                
            elseif strcmp(selectedRadio.Text , '# cell: only present frame')
                APP_opt.t5_display_Track = 2 ;
                if APP_opt.START_t5 == 1       % only if we are actively tracking
                    ReFresh_Frame;
                end
                
            elseif strcmp(selectedRadio.Text , '# cell: up to present frame')              
                APP_opt.t5_display_Track = 3 ;
                if APP_opt.START_t5 == 1       % only if we are actively tracking
                    ReFresh_Frame;
                end
            
            elseif strcmp(selectedRadio.Text , '# cell: all frames')              
                APP_opt.t5_display_Track = 4 ;
                if APP_opt.START_t5 == 1       % only if we are actively tracking
                    ReFresh_Frame;
                end  
                
           elseif strcmp(selectedRadio.Text , 'all cells: previous frame')              
                APP_opt.t5_display_Track = 5 ;
                if APP_opt.START_t5 == 1       % only if we are actively tracking
                    ReFresh_Frame;
                end 
                
            elseif strcmp(selectedRadio.Text , 'all cells: only present frame')              
                APP_opt.t5_display_Track = 6 ;
                if APP_opt.START_t5 == 1       % only if we are actively tracking
                    ReFresh_Frame;
                end 
                
            elseif strcmp(selectedRadio.Text , 'all cells: up to present frame')              
                APP_opt.t5_display_Track = 7 ;
                if APP_opt.START_t5 == 1       % only if we are actively tracking
                    ReFresh_Frame;
                end 
                
            elseif strcmp(selectedRadio.Text , 'all cells: all frames')              
                APP_opt.t5_display_Track = 8 ;
                if APP_opt.START_t5 == 1       % only if we are actively tracking
                    ReFresh_Frame;
                end 
            end
            
        end

        % Selection changed function: t5_TrackSize_ButtonGroup
        function t5_TrackSize_ButtonGroupSelectionChanged(app, event)
            global APP_opt ;
            selectedRadio = app.t5_TrackSize_ButtonGroup.SelectedObject;
            
            if  strcmp( selectedRadio.Text ,   'small')
                APP_opt.t5_display_Marker = 10 ;     % marker size  
                if APP_opt.START_t5 == 1       % only if we are actively tracking
                    ReFresh_Frame;         % REFRESH the frame and update displayed options
                end
                
            elseif strcmp(selectedRadio.Text , 'medium')
                APP_opt.t5_display_Marker = 14 ;     % marker size  
                if APP_opt.START_t5 == 1       % only if we are actively tracking
                    ReFresh_Frame;
                end
                
            elseif strcmp(selectedRadio.Text , 'large')
                APP_opt.t5_display_Marker = 18 ;     % marker size  
                if APP_opt.START_t5 == 1       % only if we are actively tracking
                    ReFresh_Frame;
                end
            end
        end

        % Value changed function: t5_choose_Outline
        function t5_choose_Outline_ValueChanged(app, event)
            global APP_opt ;
            value = app.t5_choose_Outline.Value;
            if value == 0
                act = 'off'; 
                APP_opt.t5_display_Det = 0;    % not added
            elseif value == 1
                act = 'on';
                APP_opt.t5_display_Det = 1;    % use it
            end
            app.t5_Edit_Det_File.Enable = act ;
            app.t5_Load_Det.Enable = act ; 
        end

        % Value changed function: t5_choose_Track
        function t5_choose_Track_ValueChanged(app, event)
            global APP_opt ;
            value =  app.t5_choose_Track.Value;
            if value == 0
                act = 'off'; 
                APP_opt.t5_display_Track = 0;    % not added
            elseif value == 1
                act = 'on';
                APP_opt.t5_display_Track = 1;    % use it
            end
            app.t5_Edit_Track_File.Enable = act ;
            app.t5_Load_Track.Enable = act ; 
        end

        % Button pushed function: t5_ff_back_x1
        function t5_ff_back_x1_ButtonPushed(app, event)
            global APP_opt ;
            fr_backward(app, 1) ;
        end

        % Button pushed function: t5_ff_back_x10
        function t5_ff_back_x10_ButtonPushed(app, event)
            global APP_opt ;
            fr_backward(app, 10) ;
        end

        % Button pushed function: t5_ff_forw_x1
        function t5_ff_forw_x1_ButtonPushed(app, event)
            global APP_opt ;
            fr_forward(app, 1) ;        
        end

        % Button pushed function: t5_ff_forw_x10
        function t5_ff_forw_x10ButtonPushed(app, event)
            global APP_opt ;
            fr_forward(app, 10) ;
        end

        % Value changed function: t5_showAid_CheckBox
        function t5_showAid_CheckBox_ValueChanged(app, event)
            global APP_opt ;
            value = app.t5_showAid_CheckBox.Value;
            if value == 0
                APP_opt.t5_display_TrackAid = 0;    % Display frame border Red
            elseif value == 1
                APP_opt.t5_display_TrackAid = 1;    % Display frame border Blue
            end
            
            if APP_opt.START_t5 == 1       % only if we are actively tracking
                % REFRESH the frame and update displayed options
                ReFresh_Frame;
            end   
        end

        % Value changed function: t1_CheckBox_Track
        function t1_CheckBox_Track_ValueChanged(app, event)
            global APP_opt ;
            value = app.t1_CheckBox_Track.Value;
            if value == 0
                act = 'off'; 
                c_act = 'on';                          % counter act
                APP_opt.t1_choose_ManualTrack  = 0;    % Do not load and use track.txt file
            elseif value == 1
                act = 'on';
                c_act = 'off';                         % counter act
                APP_opt.t1_choose_ManualTrack  = 1;    % perform lineage analysis
                APP_opt.t1_choose_Chan = 1;            % Force to analyse only 1 channel
            end
            
            % Check whether to enable the possibility to create clone List options
            app.t1_CheckBox_CreateCloneList.Enable = act;
            
            if app.t1_CheckBox_CreateCloneList.Value == 1          % Force to analyse only 1 channel
                app.t1_CheckBox_CreateCloneList.Value = 0;
                app.t1_Load_Det2Track.Enable = act;
                app.t1_Edit_Det2Track.Enable = act;
                app.t1_Create_cloneList.Enable = act;
            end  
            
            % Force to analyse only 1 channel           
            if app.t1_CheckBox_CH2.Value == 1  
                app.t1_CheckBox_CH2.Value = 0;
            end
            app.t1_CheckBox_CH2.Enable = c_act ;
            app.t1_Load_CH2.Enable = c_act ;
            app.t1_Edit_CH2_Fold.Enable = c_act ; 
        end

        % Value changed function: t5_TrackFile_Title
        function t5_TrackFile_Title_ValueChanged(app, event)
            global APP_opt ;
            APP_opt.t5_exp_name = app.t5_TrackFile_Title.Value; 
        end

        % Button pushed function: t5_B_CreateReport
        function t5_B_CreateReport_ButtonPushed(app, event)
            global APP_opt ;            
            if app.t5_choose_Track.Value == 1   &&  ~isempty( APP_opt.t5_fileName_Track )
               if app.t5_choose_Outline.Value == 1 
                   app.TextOUT.Value = sprintf('\n%s', [ 'Combining Detection and Track files... ']);
                   Report_Track2Det ;
                   app.TextOUT.Value = sprintf('\n%s', [ 'Idle ... ']);
               else
                   app.TextOUT.Value = sprintf('\n%s\n',  ['!!! No Det.mat file provided !!!'] );
               end                   
            else
                app.TextOUT.Value = sprintf('\n%s\n',  ['!!! No Track.txt file provided !!!'] );
            end 
        end

        % Button pushed function: t5_B_Combine_Det2Track
        function t5_B_Combine_Det2Track_ButtonPushed(app, event)
            global APP_opt ;            
            if app.t5_choose_Track.Value == 1   &&  ~isempty( APP_opt.t5_fileName_Track )
               if app.t5_choose_Outline.Value == 1 
                   app.TextOUT.Value = sprintf('\n%s', [ 'Combining Detection and Track files... ']);
                   Combine_Det2Track;
                   app.TextOUT.Value = sprintf('\n%s', [ 'Idle ... ']);
               else
                   app.TextOUT.Value = sprintf('\n%s\n',  ['!!! No Det.mat file provided !!!'] );
               end                   
            else
                app.TextOUT.Value = sprintf('\n%s\n',  ['!!! No Track.txt file provided !!!'] );
            end   
        end

        % Value changed function: t3_Exp_Title
        function t3_Exp_Title_ValueChanged(app, event)
            global APP_opt;
            APP_opt.t3_exp_name = app.t3_Exp_Title.Value ;         
        end

        % Value changed function: t3_Save_txt_CheckBox
        function t3_Save_txt_CheckBox_ValueChanged(app, event)
            global APP_opt;     
            value = app.t3_Save_txt_CheckBox.Value;  
            if value == 0
                act = 'off';
                APP_opt.t3_choose_Save_txt = 0;     % do not save the plotted data
            elseif value == 1
                act = 'on';
                APP_opt.t3_choose_Save_txt = 1;     % do not save the plotted data
            end
            app.t3_Exp_Title.Enable = act;           
        end

        % Button pushed function: t5_B_RemoveLast
        function t5_B_RemoveLast_ButtonPushed(app, event)
            BFun_Remove_Point(app)
        end

        % Value changed function: t3_FounderCell_DropDown
        function t3_FounderCell_DropDown_ValueChanged(app, event)
            global APP_opt;
            APP_opt.t3_PlotOpt_NLineage = app.t3_FounderCell_DropDown.Value;            
        end

        % Selection changed function: t3_DisplayID_Radio
        function t3_DisplayID_Radio_SelectionChanged(app, event)
            global APP_opt ;
            selectedRadio = app.t3_DisplayID_Radio.SelectedObject;
            
            if  strcmp( selectedRadio.Text ,   'none')
                APP_opt.t3_display_cID = 0 ;
            
            elseif strcmp(selectedRadio.Text , 'lineage')
                APP_opt.t3_display_cID = 1 ;
                
            elseif strcmp(selectedRadio.Text , 'manual track')
                APP_opt.t3_display_cID = 2 ;            
            end
        end

        % Button pushed function: t3_Load_cloneFolder
        function t3_Load_cloneFolder_ButtonPushed(app, event)
            global APP_opt            
            FullPath = uigetdir; 
            if FullPath == 0
                   FullPath = [];
                   app.TextOUT.Value = sprintf('\n%s\n%s',  'No path provided!!!');
                   return
            end            
            [PathName, FoldName]  = fileparts(FullPath) ;    
            APP_opt.t3_path_cloneFolder = PathName ;
            APP_opt.t3_fold_cloneFolder = FoldName ;
            app.t3_Edit_cloneFolder.Value = [APP_opt.t3_path_cloneFolder '/'  APP_opt.t3_fold_cloneFolder]; 
            
            app.TextOUT.BackgroundColor = [1 1 1]  ;
            % First check that folder contains files in appropriate format and save a list of the selected ones
            Check_CloneList_Folder(app) ;
            
        end

        % Selection changed function: t3_TypeAnalysis_Radio
        function t3_TypeAnalysis_Radio_SelectionChanged(app, event)
            global APP_opt ;
            selectedRadio = app.t3_TypeAnalysis_Radio.SelectedObject;
            
            if  strcmp( selectedRadio.Text ,   'Intergenerational')
                APP_opt.t3_Generation_VS_Single = 0 ;
                app.t3_ListBox_PlotType.Enable = 'off';
                app.t3_Edit_cloneList.Enable = 'off';         app.t3_Load_cloneList.Enable = 'off';
                app.t3_Load_cloneFolder.Enable = 'on';        app.t3_Edit_cloneFolder.Enable = 'on';
                app.t3_Summary_Plot.Enable = 'on';
            
            elseif strcmp(selectedRadio.Text , 'Single Lineage')
                APP_opt.t3_Generation_VS_Single = 1 ;   
                app.t3_ListBox_PlotType.Enable = 'on';
                app.t3_Edit_cloneList.Enable = 'on';          app.t3_Load_cloneList.Enable = 'on';
                app.t3_Load_cloneFolder.Enable = 'off';       app.t3_Edit_cloneFolder.Enable = 'off';
                app.t3_Summary_Plot.Enable = 'off';
            end
        end

        % Selection changed function: t3_AutoSave_Radio
        function t3_AutoSave_Radio_SelectionChanged(app, event)
            global APP_opt ;
            selectedRadio = app.t3_AutoSave_Radio.SelectedObject;
            if  strcmp( selectedRadio.Text ,   'No')
                APP_opt.t3_choose_AutoSave_Plot = 0 ;
                
            elseif strcmp(selectedRadio.Text , '.PDF')
                APP_opt.t3_choose_AutoSave_Plot = 1 ;   
                
            elseif strcmp(selectedRadio.Text , '.TIFF')
                APP_opt.t3_choose_AutoSave_Plot = 2 ; 
            
            end
        end

        % Value changed function: t3_Display_Plot
        function t3_Display_Plot_ValueChanged(app, event)
            global APP_opt;  
            value = app.t3_Display_Plot.Value;  
            if value == 0
                APP_opt.t3_choose_Display_Plot = 0;     % do not save the plotted data
            elseif value == 1
                APP_opt.t3_choose_Display_Plot = 1;     % do not save the plotted data
            end
        end

        % Value changed function: t3_Summary_Plot
        function t3_Summary_Plot_ValueChanged(app, event)
            global APP_opt;  
            value = app.t3_Summary_Plot.Value;  
            if value == 0
                APP_opt.t3_choose_Summary_Plot = 0;     % do not save the plotted data
            elseif value == 1
                APP_opt.t3_choose_Summary_Plot = 1;     % do not save the plotted data  
            end
        end

        % Button pushed function: t1_Create_cloneList
        function t1_Create_cloneList_ButtonPushed(app, event)
            Lng_Create_clone_List(app)
        end

        % Button pushed function: t1_Load_Det2Track
        function t1_Load_Det2Track_ButtonPushed(app, event)
            global APP_opt ;
            
            [FileName, PathName] = uigetfile({'*.mat'},'Select Det2Track.mat file'); 
            if FileName == 0 
                   FileName = [] ;
                   PathName = [] ;
                   app.TextOUT.Value = sprintf('\n%s\n%s',  'No detection.mat file provided !!!');
                   return
            end                        
            APP_opt.t1_path_Det2Track = PathName ;
            APP_opt.t1_file_Det2Track = FileName ;
            app.t1_Edit_Det2Track.Value = [APP_opt.t1_path_Det2Track , APP_opt.t1_file_Det2Track];
        end

        % Value changed function: t1_CheckBox_CreateCloneList
        function t1_CheckBox_CreateCloneList_ValueChanged(app, event)
            global APP_opt ;
            value = app.t1_CheckBox_CreateCloneList.Value;;
            if value == 0
                act = 'off'; 
                 elseif value == 1
                act = 'on';
            end            
            app.t1_Load_Det2Track.Enable = act;
            app.t1_Edit_Det2Track.Enable = act;
            app.t1_Create_cloneList.Enable = act;
             
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.Tab = uitabgroup(app.UIFigure);
            app.Analysis_Tab = uitab(app.Tab);
            app.Analysis_Tab.Title = 'Analyse Fluorescence';
            app.Analysis_Tab.Units = 'pixels';
            app.BFstackLabel = uilabel(app.Analysis_Tab);
            app.BFstackLabel.Text = 'BF stack';
            app.t1_Edit_BF_Fold = uieditfield(app.Analysis_Tab, 'text');
            app.t1_Edit_BF_Fold.Editable = 'off';
            app.Ch1stackLabel = uilabel(app.Analysis_Tab);
            app.Ch1stackLabel.Text = 'Ch. 1 stack';
            app.t1_Load_BF = uibutton(app.Analysis_Tab, 'push');
            app.t1_Load_BF.ButtonPushedFcn = createCallbackFcn(app, @t1_Load_BF_ButtonPushed, true);
            app.t1_Load_BF.Text = 'Load...';
            app.t1_Alg_Panel = uipanel(app.Analysis_Tab);
            app.t1_Alg_Panel.Title = 'Algorithm';
            app.t1_Alg_Radio = uibuttongroup(app.t1_Alg_Panel);
            app.t1_Alg_Radio.SelectionChangedFcn = createCallbackFcn(app, @t1_Alg_Radio_SelectionChanged, true);
            app.Memb_2_CytoButton = uiradiobutton(app.t1_Alg_Radio);
            app.Memb_2_CytoButton.Text = 'Memb_2_Cyto';
            app.Polar_SigButton = uiradiobutton(app.t1_Alg_Radio);
            app.Polar_SigButton.Text = 'Polar_Sig';
            app.AvgSignalButton = uiradiobutton(app.t1_Alg_Radio);
            app.AvgSignalButton.Text = 'Avg Signal';
            app.AvgSignalButton.Value = true;
            app.Edit_MC_Memb_sz_3Label = uilabel(app.t1_Alg_Panel);
            app.Edit_MC_Memb_sz_3Label.Text = 'Membr. size:';
            app.t1_Edit_MC_Memb_sz = uieditfield(app.t1_Alg_Panel, 'numeric');
            app.t1_Edit_MC_Memb_sz.ValueChangedFcn = createCallbackFcn(app, @t1_Edit_MC_Memb_sz_ValueChanged, true);
            app.t1_Edit_MC_Memb_sz.Limits = [1 1000];
            app.t1_Edit_MC_Memb_sz.Enable = 'off';
            app.t1_Edit_MC_Memb_sz.Value = 3;
            app.t1_Edit_PL_CH2_Search = uieditfield(app.t1_Alg_Panel, 'numeric');
            app.t1_Edit_PL_CH2_Search.ValueChangedFcn = createCallbackFcn(app, @t1_Edit_PL_CH2_Search_ValueChanged, true);
            app.t1_Edit_PL_CH2_Search.Enable = 'off';
            app.t1_Edit_PL_CH2_Search.Value = 10;
            app.t1_Edit_PL_CH2_ScaleFact = uieditfield(app.t1_Alg_Panel, 'numeric');
            app.t1_Edit_PL_CH2_ScaleFact.ValueChangedFcn = createCallbackFcn(app, @t1_Edit_PL_CH2_ScaleFact_ValueChanged, true);
            app.t1_Edit_PL_CH2_ScaleFact.Enable = 'off';
            app.t1_Edit_PL_CH2_ScaleFact.Value = 0.7;
            app.t1_Edit_PL_CH2_Peak_Rad = uieditfield(app.t1_Alg_Panel, 'numeric');
            app.t1_Edit_PL_CH2_Peak_Rad.ValueChangedFcn = createCallbackFcn(app, @t1_Edit_PL_CH2_Peak_Rad_ValueChanged, true);
            app.t1_Edit_PL_CH2_Peak_Rad.Enable = 'off';
            app.t1_Edit_PL_CH2_Peak_Rad.Value = 2.5;
            app.Ch1_2Label = uilabel(app.t1_Alg_Panel);
            app.Ch1_2Label.Text = 'Ch. 1';
            app.Ch2_2Label = uilabel(app.t1_Alg_Panel);
            app.Ch2_2Label.Text = 'Ch. 2';
            app.SearchcircleEditFieldLabel = uilabel(app.t1_Alg_Panel);
            app.SearchcircleEditFieldLabel.Text = 'Search circle:';
            app.t1_Edit_PL_CH1_Search = uieditfield(app.t1_Alg_Panel, 'numeric');
            app.t1_Edit_PL_CH1_Search.ValueChangedFcn = createCallbackFcn(app, @t1_Edit_PL_CH1_Search_ValueChanged, true);
            app.t1_Edit_PL_CH1_Search.Enable = 'off';
            app.t1_Edit_PL_CH1_Search.Value = 10;
            app.ScaleFactorEditFieldLabel = uilabel(app.t1_Alg_Panel);
            app.ScaleFactorEditFieldLabel.Text = 'Scale Factor:';
            app.t1_Edit_PL_CH1_ScaleFact = uieditfield(app.t1_Alg_Panel, 'numeric');
            app.t1_Edit_PL_CH1_ScaleFact.ValueChangedFcn = createCallbackFcn(app, @t1_Edit_PL_CH1_ScaleFact_ValueChanged, true);
            app.t1_Edit_PL_CH1_ScaleFact.Enable = 'off';
            app.t1_Edit_PL_CH1_ScaleFact.Value = 0.7;
            app.SignalRadiuspxLabel = uilabel(app.t1_Alg_Panel);
            app.SignalRadiuspxLabel.Text = 'Signal Radius [px]:';
            app.t1_Edit_PL_CH1_Peak_Rad = uieditfield(app.t1_Alg_Panel, 'numeric');
            app.t1_Edit_PL_CH1_Peak_Rad.ValueChangedFcn = createCallbackFcn(app, @t1_Edit_PL_CH1_Peak_Rad_ValueChanged, true);
            app.t1_Edit_PL_CH1_Peak_Rad.Enable = 'off';
            app.t1_Edit_PL_CH1_Peak_Rad.Value = 2.5;
            app.START_t1 = uibutton(app.Analysis_Tab, 'push');
            app.START_t1.ButtonPushedFcn = createCallbackFcn(app, @START_t1_ButtonPushed, true);
            app.START_t1.Text = 'START';
            app.STOP_t1 = uibutton(app.Analysis_Tab, 'push');
            app.STOP_t1.ButtonPushedFcn = createCallbackFcn(app, @STOP_t1_ButtonPushed, true);
            app.STOP_t1.Text = 'STOP';
            app.BoxborderpxEditFieldLabel = uilabel(app.Analysis_Tab);
            app.BoxborderpxEditFieldLabel.Text = 'Box border [px]:';
            app.t1_Edit_BorderBox = uieditfield(app.Analysis_Tab, 'numeric');
            app.t1_Edit_BorderBox.ValueChangedFcn = createCallbackFcn(app, @t1_Edit_BorderBox_ValueChanged, true);
            app.t1_Edit_BorderBox.Limits = [5 Inf];
            app.t1_Edit_BorderBox.Value = 10;
            app.t1_ShowPlot = uicheckbox(app.Analysis_Tab);
            app.t1_ShowPlot.ValueChangedFcn = createCallbackFcn(app, @t1_ShowPlot_ValueChanged, true);
            app.t1_ShowPlot.Text = 'Display analysis for             sec.';
            app.SaveasLabel = uilabel(app.Analysis_Tab);
            app.SaveasLabel.Text = 'Save as:';
            app.t1_Exp_Title = uieditfield(app.Analysis_Tab, 'text');
            app.t1_Exp_Title.ValueChangedFcn = createCallbackFcn(app, @t1_Exp_Title_ValueChanged, true);
            app.t1_Edit_WaitPlot = uieditfield(app.Analysis_Tab, 'numeric');
            app.t1_Edit_WaitPlot.ValueChangedFcn = createCallbackFcn(app, @t1_Edit_WaitPlot_ValueChanged, true);
            app.t1_Edit_WaitPlot.ValueDisplayFormat = '%11.3g';
            app.t1_Edit_WaitPlot.Value = 2;
            app.Ch2stackLabel = uilabel(app.Analysis_Tab);
            app.Ch2stackLabel.Text = 'Ch. 2 stack';
            app.t1_Edit_CH2_Fold = uieditfield(app.Analysis_Tab, 'text');
            app.t1_Edit_CH2_Fold.Enable = 'off';
            app.t1_Edit_CH2_Fold.Editable = 'off';
            app.t1_Edit_CH1_Fold = uieditfield(app.Analysis_Tab, 'text');
            app.t1_Edit_CH1_Fold.Editable = 'off';
            app.t1_Load_CH2 = uibutton(app.Analysis_Tab, 'push');
            app.t1_Load_CH2.ButtonPushedFcn = createCallbackFcn(app, @t1_Load_CH2_ButtonPushed, true);
            app.t1_Load_CH2.Enable = 'off';
            app.t1_Load_CH2.Text = 'Load...';
            app.t1_Load_CH1 = uibutton(app.Analysis_Tab, 'push');
            app.t1_Load_CH1.ButtonPushedFcn = createCallbackFcn(app, @t1_Load_CH1_ButtonPushed, true);
            app.t1_Load_CH1.Text = 'Load...';
            app.t1_CheckBox_CH2 = uicheckbox(app.Analysis_Tab);
            app.t1_CheckBox_CH2.ValueChangedFcn = createCallbackFcn(app, @t1_CheckBox_CH2_ValueChanged, true);
            app.t1_CheckBox_CH2.Text = '';
            app.t1_Check_CH1_Marker = uicheckbox(app.Analysis_Tab);
            app.t1_Check_CH1_Marker.Text = 'CH1 is Marker';
            app.t1_Load_Det = uibutton(app.Analysis_Tab, 'push');
            app.t1_Load_Det.ButtonPushedFcn = createCallbackFcn(app, @t1_Load_Det_ButtonPushed, true);
            app.t1_Load_Det.Text = 'Load...';
            app.DetmatLabel_2 = uilabel(app.Analysis_Tab);
            app.DetmatLabel_2.Text = 'Det.mat';
            app.t1_Edit_Det_file = uieditfield(app.Analysis_Tab, 'text');
            app.t1_Edit_Det_file.Editable = 'off';
            app.t1_CheckBox_Track = uicheckbox(app.Analysis_Tab);
            app.t1_CheckBox_Track.ValueChangedFcn = createCallbackFcn(app, @t1_CheckBox_Track_ValueChanged, true);
            app.t1_CheckBox_Track.Text = 'Det.mat combined with  Manual Tracking';
            app.t1_Load_Det2Track = uibutton(app.Analysis_Tab, 'push');
            app.t1_Load_Det2Track.ButtonPushedFcn = createCallbackFcn(app, @t1_Load_Det2Track_ButtonPushed, true);
            app.t1_Load_Det2Track.Enable = 'off';
            app.t1_Load_Det2Track.Text = 'Load...';
            app.Det2TrackmatLabel = uilabel(app.Analysis_Tab);
            app.Det2TrackmatLabel.Text = 'Det2Track.mat';
            app.t1_Edit_Det2Track = uieditfield(app.Analysis_Tab, 'text');
            app.t1_Edit_Det2Track.Enable = 'off';
            app.t1_Edit_Det2Track.Editable = 'off';
            app.t1_Create_cloneList = uibutton(app.Analysis_Tab, 'push');
            app.t1_Create_cloneList.ButtonPushedFcn = createCallbackFcn(app, @t1_Create_cloneList_ButtonPushed, true);
            app.t1_Create_cloneList.Enable = 'off';
            app.t1_Create_cloneList.Text = 'Create';
            app.Label_CloneList = uilabel(app.Analysis_Tab);
            app.Label_CloneList.Text = 'Create cloneList.mat:';
            app.t1_CheckBox_CreateCloneList = uicheckbox(app.Analysis_Tab);
            app.t1_CheckBox_CreateCloneList.ValueChangedFcn = createCallbackFcn(app, @t1_CheckBox_CreateCloneList_ValueChanged, true);
            app.t1_CheckBox_CreateCloneList.Enable = 'off';
            app.t1_CheckBox_CreateCloneList.Text = '';
            app.Lineage_Tab = uitab(app.Tab);
            app.Lineage_Tab.Title = 'Lineage Plotting';
            app.Lineage_Tab.Units = 'pixels';
            app.t3_Load_cloneList = uibutton(app.Lineage_Tab, 'push');
            app.t3_Load_cloneList.ButtonPushedFcn = createCallbackFcn(app, @t3_Load_cloneList_ButtonPushed, true);
            app.t3_Load_cloneList.Text = 'Load...';
            app.clonesmatfileLabel = uilabel(app.Lineage_Tab);
            app.clonesmatfileLabel.Text = 'clones.mat file';
            app.t3_Edit_cloneList = uieditfield(app.Lineage_Tab, 'text');
            app.t3_Edit_cloneList.Editable = 'off';
            app.PLOT_t3 = uibutton(app.Lineage_Tab, 'push');
            app.PLOT_t3.ButtonPushedFcn = createCallbackFcn(app, @PLOT_t3_ButtonPushed, true);
            app.PLOT_t3.Text = 'PLOT';
            app.SizeborderlineLabel = uilabel(app.Lineage_Tab);
            app.SizeborderlineLabel.Text = 'Size border line';
            app.t3_EditField_Borderline = uieditfield(app.Lineage_Tab, 'numeric');
            app.t3_EditField_Borderline.ValueChangedFcn = createCallbackFcn(app, @t3_EditField_Borderline_ValueChanged, true);
            app.t3_EditField_Borderline.Value = 1.5;
            app.LinethicknessLabel = uilabel(app.Lineage_Tab);
            app.LinethicknessLabel.Text = 'Line thickness';
            app.t3_EditField_LineSize = uieditfield(app.Lineage_Tab, 'numeric');
            app.t3_EditField_LineSize.ValueChangedFcn = createCallbackFcn(app, @t3_EditField_LineSize_ValueChanged, true);
            app.t3_EditField_LineSize.Value = 10;
            app.DistancebtwlinesLabel = uilabel(app.Lineage_Tab);
            app.DistancebtwlinesLabel.Text = 'Distance btw lines';
            app.t3_EditField_LinesDistance = uieditfield(app.Lineage_Tab, 'numeric');
            app.t3_EditField_LinesDistance.ValueChangedFcn = createCallbackFcn(app, @t3_EditField_LinesDistance_ValueChanged, true);
            app.t3_EditField_LinesDistance.Value = 25;
            app.t3_CheckBox_DisplayPoles = uicheckbox(app.Lineage_Tab);
            app.t3_CheckBox_DisplayPoles.ValueChangedFcn = createCallbackFcn(app, @t3_CheckBox_DisplayPoles_ValueChanged, true);
            app.t3_CheckBox_DisplayPoles.Text = 'Show Poles';
            app.t3_CheckBox_DisplayPoles.Value = true;
            app.ColorMapDropDownLabel = uilabel(app.Lineage_Tab);
            app.ColorMapDropDownLabel.Text = 'ColorMap';
            app.t3_DropDown_LUT = uidropdown(app.Lineage_Tab);
            app.t3_DropDown_LUT.Items = {'1', '2', '3', '4'};
            app.t3_DropDown_LUT.ValueChangedFcn = createCallbackFcn(app, @t3_DropDown_LUT_ValueChanged, true);
            app.t3_DropDown_LUT.Value = '1';
            app.frameXminLabel = uilabel(app.Lineage_Tab);
            app.frameXminLabel.Text = '1 frame = X min';
            app.t3_EditField_fpmCH1 = uieditfield(app.Lineage_Tab, 'numeric');
            app.t3_EditField_fpmCH1.ValueChangedFcn = createCallbackFcn(app, @t3_EditField_fpmCH1_ValueChanged, true);
            app.t3_EditField_fpmCH1.Limits = [1 Inf];
            app.t3_EditField_fpmCH1.Value = 1;
            app.PlottypeListBoxLabel = uilabel(app.Lineage_Tab);
            app.PlottypeListBoxLabel.Text = 'Plot type';
            app.t3_ListBox_PlotType = uilistbox(app.Lineage_Tab);
            app.t3_ListBox_PlotType.Items = {'1 - Lineage Tree', '2 - Individual Clones', '3 - Manual Tracks'};
            app.t3_ListBox_PlotType.ValueChangedFcn = createCallbackFcn(app, @t3_ListBox_PlotType_ValueChanged, true);
            app.t3_ListBox_PlotType.Value = '1 - Lineage Tree';
            app.LinetypeListBoxLabel = uilabel(app.Lineage_Tab);
            app.LinetypeListBoxLabel.Text = 'Line type';
            app.t3_ListBox_LineType = uilistbox(app.Lineage_Tab);
            app.t3_ListBox_LineType.Items = {'1 - single value', '2 - bi-value', '3 - single value Ratio', '4 - segmentation'};
            app.t3_ListBox_LineType.ValueChangedFcn = createCallbackFcn(app, @t3_ListBox_LineType_ValueChanged, true);
            app.t3_ListBox_LineType.Value = '1 - single value';
            app.ValuetouseforplottingLabel = uilabel(app.Lineage_Tab);
            app.ValuetouseforplottingLabel.Text = 'Value to use for plotting';
            app.t3_DropDownValue = uidropdown(app.Lineage_Tab);
            app.t3_DropDownValue.Items = {'1 - Whole Cell', '2 - Membrane', '3 - Old_pole', '4 - New_pole'};
            app.t3_DropDownValue.ValueChangedFcn = createCallbackFcn(app, @t3_DropDownValue_ValueChanged, true);
            app.t3_DropDownValue.Value = '1 - Whole Cell';
            app.t3_CheckBox_DisplayExI = uicheckbox(app.Lineage_Tab);
            app.t3_CheckBox_DisplayExI.ValueChangedFcn = createCallbackFcn(app, @t3_CheckBox_DisplayExI_ValueChanged, true);
            app.t3_CheckBox_DisplayExI.Text = 'Show Extra info';
            app.ValueRangeEditFieldLabel = uilabel(app.Lineage_Tab);
            app.ValueRangeEditFieldLabel.Text = 'Value Range';
            app.t3_ValueRange = uieditfield(app.Lineage_Tab, 'text');
            app.t3_ValueRange.ValueChangedFcn = createCallbackFcn(app, @t3_ValueRange_ValueChanged, true);
            app.t3_ValueRange.Value = 'min:max';
            app.SegmentsizeEditFieldLabel = uilabel(app.Lineage_Tab);
            app.SegmentsizeEditFieldLabel.Text = 'Segment size';
            app.t3_EditField_SegmentSize = uieditfield(app.Lineage_Tab, 'numeric');
            app.t3_EditField_SegmentSize.ValueChangedFcn = createCallbackFcn(app, @t3_EditField_SegmentSize_ValueChanged, true);
            app.t3_EditField_SegmentSize.Limits = [1 Inf];
            app.t3_EditField_SegmentSize.Value = 1;
            app.t3_Save_txt_CheckBox = uicheckbox(app.Lineage_Tab);
            app.t3_Save_txt_CheckBox.ValueChangedFcn = createCallbackFcn(app, @t3_Save_txt_CheckBox_ValueChanged, true);
            app.t3_Save_txt_CheckBox.Text = '';
            app.t3_DisplayID_Radio = uibuttongroup(app.Lineage_Tab);
            app.t3_DisplayID_Radio.SelectionChangedFcn = createCallbackFcn(app, @t3_DisplayID_Radio_SelectionChanged, true);
            app.t3_DisplayID_Radio.Title = 'show ID in plot:';
            app.noneButton_3 = uiradiobutton(app.t3_DisplayID_Radio);
            app.noneButton_3.Text = 'none';
            app.noneButton_3.Value = true;
            app.lineageButton = uiradiobutton(app.t3_DisplayID_Radio);
            app.lineageButton.Text = 'lineage';
            app.manualtrackButton = uiradiobutton(app.t3_DisplayID_Radio);
            app.manualtrackButton.Text = 'manual track';
            app.t3_Load_cloneFolder = uibutton(app.Lineage_Tab, 'push');
            app.t3_Load_cloneFolder.ButtonPushedFcn = createCallbackFcn(app, @t3_Load_cloneFolder_ButtonPushed, true);
            app.t3_Load_cloneFolder.Enable = 'off';
            app.t3_Load_cloneFolder.Text = 'Load...';
            app.loadFolderLabel = uilabel(app.Lineage_Tab);
            app.loadFolderLabel.Enable = 'off';
            app.loadFolderLabel.Text = 'load Folder';
            app.t3_Edit_cloneFolder = uieditfield(app.Lineage_Tab, 'text');
            app.t3_Edit_cloneFolder.Enable = 'off';
            app.t3_Edit_cloneFolder.Editable = 'off';
            app.t3_TypeAnalysis_Radio = uibuttongroup(app.Lineage_Tab);
            app.t3_TypeAnalysis_Radio.SelectionChangedFcn = createCallbackFcn(app, @t3_TypeAnalysis_Radio_SelectionChanged, true);
            app.t3_TypeAnalysis_Radio.Title = 'Analyse:';
            app.IntergenerationalButton = uiradiobutton(app.t3_TypeAnalysis_Radio);
            app.IntergenerationalButton.Text = 'Intergenerational';
            app.SingleLineageButton = uiradiobutton(app.t3_TypeAnalysis_Radio);
            app.SingleLineageButton.Text = 'Single Lineage';
            app.SingleLineageButton.Value = true;
            app.FounderCellLabel = uilabel(app.Lineage_Tab);
            app.FounderCellLabel.Text = 'Founder Cell';
            app.t3_FounderCell_DropDown = uidropdown(app.Lineage_Tab);
            app.t3_FounderCell_DropDown.Items = {};
            app.t3_FounderCell_DropDown.ValueChangedFcn = createCallbackFcn(app, @t3_FounderCell_DropDown_ValueChanged, true);
            app.t3_FounderCell_DropDown.Value = {};
            app.saveplotteddataastxtfileLabel = uilabel(app.Lineage_Tab);
            app.saveplotteddataastxtfileLabel.Text = 'save plotted data (*) as .txt file';
            app.t3_Exp_Title = uieditfield(app.Lineage_Tab, 'text');
            app.t3_Exp_Title.ValueChangedFcn = createCallbackFcn(app, @t3_Exp_Title_ValueChanged, true);
            app.t3_Exp_Title.Enable = 'off';
            app.t3_AutoSave_Radio = uibuttongroup(app.Lineage_Tab);
            app.t3_AutoSave_Radio.SelectionChangedFcn = createCallbackFcn(app, @t3_AutoSave_Radio_SelectionChanged, true);
            app.t3_AutoSave_Radio.Title = 'Autosave plot(s):';
            app.NoButton = uiradiobutton(app.t3_AutoSave_Radio);
            app.NoButton.Text = 'No';
            app.NoButton.Value = true;
            app.PDFButton = uiradiobutton(app.t3_AutoSave_Radio);
            app.PDFButton.Text = '.PDF';
            app.TIFFButton = uiradiobutton(app.t3_AutoSave_Radio);
            app.TIFFButton.Text = '.TIFF';
            app.Label_Asterix2 = uilabel(app.Lineage_Tab);
            app.Label_Asterix2.Text = '*';
            app.Label_Asterix1 = uilabel(app.Lineage_Tab);
            app.Label_Asterix1.Text = '*';
            app.t3_Display_Plot = uicheckbox(app.Lineage_Tab);
            app.t3_Display_Plot.ValueChangedFcn = createCallbackFcn(app, @t3_Display_Plot_ValueChanged, true);
            app.t3_Display_Plot.Text = 'Display plot(s)';
            app.t3_Display_Plot.Value = true;
            app.t3_Summary_Plot = uicheckbox(app.Lineage_Tab);
            app.t3_Summary_Plot.ValueChangedFcn = createCallbackFcn(app, @t3_Summary_Plot_ValueChanged, true);
            app.t3_Summary_Plot.Enable = 'off';
            app.t3_Summary_Plot.Text = 'Summary plot';
            app.Manual_Tracking_Tab = uitab(app.Tab);
            app.Manual_Tracking_Tab.Title = 'Manual Tracking';
            app.Manual_Tracking_Tab.Units = 'pixels';
            app.START_t5 = uibutton(app.Manual_Tracking_Tab, 'push');
            app.START_t5.ButtonPushedFcn = createCallbackFcn(app, @START_t5_ButtonPushed, true);
            app.START_t5.Text = 'START';
            app.STOP_t5 = uibutton(app.Manual_Tracking_Tab, 'push');
            app.STOP_t5.ButtonPushedFcn = createCallbackFcn(app, @STOP_t5_ButtonPushed, true);
            app.STOP_t5.Text = 'STOP';
            app.BFstackEditFieldLabel = uilabel(app.Manual_Tracking_Tab);
            app.BFstackEditFieldLabel.Text = 'BF stack';
            app.t5_Edit_BF_Fold = uieditfield(app.Manual_Tracking_Tab, 'text');
            app.DetmatLabel = uilabel(app.Manual_Tracking_Tab);
            app.DetmatLabel.Text = 'Det.mat';
            app.t5_Edit_Det_File = uieditfield(app.Manual_Tracking_Tab, 'text');
            app.t5_Edit_Det_File.Enable = 'off';
            app.t5_Load_BF = uibutton(app.Manual_Tracking_Tab, 'push');
            app.t5_Load_BF.ButtonPushedFcn = createCallbackFcn(app, @t5_Load_BF_ButtonPushed, true);
            app.t5_Load_BF.Text = 'Load...';
            app.t5_Load_Det = uibutton(app.Manual_Tracking_Tab, 'push');
            app.t5_Load_Det.ButtonPushedFcn = createCallbackFcn(app, @t5_Load_Det_ButtonPushed, true);
            app.t5_Load_Det.Enable = 'off';
            app.t5_Load_Det.Text = 'Load...';
            app.t5_choose_Outline = uicheckbox(app.Manual_Tracking_Tab);
            app.t5_choose_Outline.ValueChangedFcn = createCallbackFcn(app, @t5_choose_Outline_ValueChanged, true);
            app.t5_choose_Outline.Text = '';
            app.t5_ff_forw_x1 = uibutton(app.Manual_Tracking_Tab, 'push');
            app.t5_ff_forw_x1.ButtonPushedFcn = createCallbackFcn(app, @t5_ff_forw_x1_ButtonPushed, true);
            app.t5_ff_forw_x1.Text = '>';
            app.t5_ff_back_x1 = uibutton(app.Manual_Tracking_Tab, 'push');
            app.t5_ff_back_x1.ButtonPushedFcn = createCallbackFcn(app, @t5_ff_back_x1_ButtonPushed, true);
            app.t5_ff_back_x1.Text = '<';
            app.t5_ff_forw_x10 = uibutton(app.Manual_Tracking_Tab, 'push');
            app.t5_ff_forw_x10.ButtonPushedFcn = createCallbackFcn(app, @t5_ff_forw_x10ButtonPushed, true);
            app.t5_ff_forw_x10.Text = '>>';
            app.t5_ff_back_x10 = uibutton(app.Manual_Tracking_Tab, 'push');
            app.t5_ff_back_x10.ButtonPushedFcn = createCallbackFcn(app, @t5_ff_back_x10_ButtonPushed, true);
            app.t5_ff_back_x10.Text = '<<';
            app.t5_Edit_Frame = uieditfield(app.Manual_Tracking_Tab, 'numeric');
            app.t5_Edit_Frame.ValueChangedFcn = createCallbackFcn(app, @t5_Edit_Frame_ValueChanged, true);
            app.t5_Edit_Frame.Limits = [1 Inf];
            app.t5_Edit_Frame.Value = 1;
            app.t5_Label_totFrames = uilabel(app.Manual_Tracking_Tab);
            app.t5_Label_totFrames.Text = '   ';
            app.t5_Load_Track = uibutton(app.Manual_Tracking_Tab, 'push');
            app.t5_Load_Track.ButtonPushedFcn = createCallbackFcn(app, @t5_Load_Track_ButtonPushed, true);
            app.t5_Load_Track.Enable = 'off';
            app.t5_Load_Track.Text = 'Load...';
            app.TracktxtLabel = uilabel(app.Manual_Tracking_Tab);
            app.TracktxtLabel.Text = 'Track.txt';
            app.t5_Edit_Track_File = uieditfield(app.Manual_Tracking_Tab, 'text');
            app.t5_Edit_Track_File.Enable = 'off';
            app.t5_B_Delete = uibutton(app.Manual_Tracking_Tab, 'push');
            app.t5_B_Delete.ButtonPushedFcn = createCallbackFcn(app, @t5_B_DeleteButtonPushed, true);
            app.t5_B_Delete.Text = 'Delete';
            app.t5_B_Add = uibutton(app.Manual_Tracking_Tab, 'push');
            app.t5_B_Add.ButtonPushedFcn = createCallbackFcn(app, @t5_B_Add_ButtonPushed, true);
            app.t5_B_Add.Text = 'Add';
            app.t5_B_Delete_All = uibutton(app.Manual_Tracking_Tab, 'push');
            app.t5_B_Delete_All.ButtonPushedFcn = createCallbackFcn(app, @t5_B_Delete_All_ButtonPushed, true);
            app.t5_B_Delete_All.Text = 'Delete All';
            app.SelectTrackLabel = uilabel(app.Manual_Tracking_Tab);
            app.SelectTrackLabel.Text = 'Select Track';
            app.t5_SelectCellDropDown = uidropdown(app.Manual_Tracking_Tab);
            app.t5_SelectCellDropDown.Items = {};
            app.t5_SelectCellDropDown.ValueChangedFcn = createCallbackFcn(app, @t5_SelectCellDropDown_ValueChanged, true);
            app.t5_SelectCellDropDown.Value = {};
            app.t5_B_SaveTrack = uibutton(app.Manual_Tracking_Tab, 'push');
            app.t5_B_SaveTrack.ButtonPushedFcn = createCallbackFcn(app, @t5_B_SaveTrack_ButtonPushed, true);
            app.t5_B_SaveTrack.Text = 'Save Tracks';
            app.t5_B_Clear = uibutton(app.Manual_Tracking_Tab, 'push');
            app.t5_B_Clear.ButtonPushedFcn = createCallbackFcn(app, @t5_B_Clear_ButtonPushed, true);
            app.t5_B_Clear.Text = 'Clear';
            app.t5_ShowTracks_Radio = uibuttongroup(app.Manual_Tracking_Tab);
            app.t5_ShowTracks_Radio.SelectionChangedFcn = createCallbackFcn(app, @t5_ShowTracks_Radio_SelectionChanged, true);
            app.t5_ShowTracks_Radio.Title = 'Show Tracks:';
            app.noneButton = uiradiobutton(app.t5_ShowTracks_Radio);
            app.noneButton.Text = 'none';
            app.noneButton.Value = true;
            app.cellpreviousframeButton = uiradiobutton(app.t5_ShowTracks_Radio);
            app.cellpreviousframeButton.Text = '# cell: previous frame';
            app.cellonlypresentframeButton_2 = uiradiobutton(app.t5_ShowTracks_Radio);
            app.cellonlypresentframeButton_2.Text = '# cell: only present frame';
            app.celluptopresentframeButton_2 = uiradiobutton(app.t5_ShowTracks_Radio);
            app.celluptopresentframeButton_2.Text = '# cell: up to present frame';
            app.cellallframesButton_2 = uiradiobutton(app.t5_ShowTracks_Radio);
            app.cellallframesButton_2.Text = '# cell: all frames';
            app.allcellspreviousframeButton = uiradiobutton(app.t5_ShowTracks_Radio);
            app.allcellspreviousframeButton.Text = 'all cells: previous frame';
            app.allcellsonlypresentframeButton_2 = uiradiobutton(app.t5_ShowTracks_Radio);
            app.allcellsonlypresentframeButton_2.Text = 'all cells: only present frame';
            app.allcellsuptopresentframeButton_2 = uiradiobutton(app.t5_ShowTracks_Radio);
            app.allcellsuptopresentframeButton_2.Text = 'all cells: up to present frame';
            app.allcellsallframesButton_2 = uiradiobutton(app.t5_ShowTracks_Radio);
            app.allcellsallframesButton_2.Text = 'all cells: all frames';
            app.t5_showAid_CheckBox = uicheckbox(app.Manual_Tracking_Tab);
            app.t5_showAid_CheckBox.ValueChangedFcn = createCallbackFcn(app, @t5_showAid_CheckBox_ValueChanged, true);
            app.t5_showAid_CheckBox.Text = 'display aid';
            app.t5_choose_Track = uicheckbox(app.Manual_Tracking_Tab);
            app.t5_choose_Track.ValueChangedFcn = createCallbackFcn(app, @t5_choose_Track_ValueChanged, true);
            app.t5_choose_Track.Text = '';
            app.t5_Color_ButtonGroup = uibuttongroup(app.Manual_Tracking_Tab);
            app.t5_Color_ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @t5_Color_ButtonGroupSelectionChanged, true);
            app.t5_Color_ButtonGroup.Title = 'Track Color:';
            app.RedButton = uiradiobutton(app.t5_Color_ButtonGroup);
            app.RedButton.Text = 'Red';
            app.RedButton.Value = true;
            app.YellowButton = uiradiobutton(app.t5_Color_ButtonGroup);
            app.YellowButton.Text = 'Yellow';
            app.t5_TrackSize_ButtonGroup = uibuttongroup(app.Manual_Tracking_Tab);
            app.t5_TrackSize_ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @t5_TrackSize_ButtonGroupSelectionChanged, true);
            app.t5_TrackSize_ButtonGroup.Title = 'Track Size:';
            app.smallButton = uiradiobutton(app.t5_TrackSize_ButtonGroup);
            app.smallButton.Text = 'small';
            app.smallButton.Value = true;
            app.mediumButton = uiradiobutton(app.t5_TrackSize_ButtonGroup);
            app.mediumButton.Text = 'medium';
            app.largeButton = uiradiobutton(app.t5_TrackSize_ButtonGroup);
            app.largeButton.Text = 'large';
            app.t5_CellOutline_ButtonGroup = uibuttongroup(app.Manual_Tracking_Tab);
            app.t5_CellOutline_ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @t5_CellOutline_ButtonGroupSelectionChanged, true);
            app.t5_CellOutline_ButtonGroup.Title = 'Cell Outline:';
            app.noneButton_2 = uiradiobutton(app.t5_CellOutline_ButtonGroup);
            app.noneButton_2.Text = 'none';
            app.noneButton_2.Value = true;
            app.GreenButton = uiradiobutton(app.t5_CellOutline_ButtonGroup);
            app.GreenButton.Text = 'Green';
            app.AstrackButton = uiradiobutton(app.t5_CellOutline_ButtonGroup);
            app.AstrackButton.Text = 'As track';
            app.t1_Exp_Title_2Label = uilabel(app.Manual_Tracking_Tab);
            app.t1_Exp_Title_2Label.Text = 'Save as:';
            app.t5_TrackFile_Title = uieditfield(app.Manual_Tracking_Tab, 'text');
            app.t5_TrackFile_Title.ValueChangedFcn = createCallbackFcn(app, @t5_TrackFile_Title_ValueChanged, true);
            app.t5_B_CreateReport = uibutton(app.Manual_Tracking_Tab, 'push');
            app.t5_B_CreateReport.ButtonPushedFcn = createCallbackFcn(app, @t5_B_CreateReport_ButtonPushed, true);
            app.t5_B_CreateReport.Text = 'Create';
            app.TrackingReport_Label = uilabel(app.Manual_Tracking_Tab);
            app.TrackingReport_Label.Text = 'Tracking Report:';
            app.t5_B_Combine_Det2Track = uibutton(app.Manual_Tracking_Tab, 'push');
            app.t5_B_Combine_Det2Track.ButtonPushedFcn = createCallbackFcn(app, @t5_B_Combine_Det2Track_ButtonPushed, true);
            app.t5_B_Combine_Det2Track.Text = 'Combine';
            app.Combine_Label = uilabel(app.Manual_Tracking_Tab);
            app.Combine_Label.Text = 'Combine Det.mat and Track.txt:';
            app.t5_B_RemoveLast = uibutton(app.Manual_Tracking_Tab, 'push');
            app.t5_B_RemoveLast.ButtonPushedFcn = createCallbackFcn(app, @t5_B_RemoveLast_ButtonPushed, true);
            app.t5_B_RemoveLast.Text = 'Remove last point(s)';
            app.TextOUT = uitextarea(app.UIFigure);
            app.TextOUT.Editable = 'off';
            app.TextOUT.Value = {''; '... IDLE ...'; ''};
        end
    end

    methods (Access = public)

        % Construct app
        function app = WHISIT()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end